classdef ARSGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutoresuscitationAnalysisGUIUIFigure  matlab.ui.Figure
        challengeLabels             matlab.ui.container.Menu
        InhaleVolumesMenu           matlab.ui.container.Menu
        InhaleAmplitudesMenu        matlab.ui.container.Menu
        ChallengeMarkersMenu        matlab.ui.container.Menu
        PlotMarkersMenu             matlab.ui.container.Menu
        EditEventMarkersMenu        matlab.ui.container.Menu
        ShiftFirstApneaMenu         matlab.ui.container.Menu
        HRRestorationMenu           matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        CurrentFile                 matlab.ui.control.EditField
        CurrentFileEditFieldLabel   matlab.ui.control.Label
        NumOfAnoxias                matlab.ui.control.NumericEditField
        Label_2                     matlab.ui.control.Label
        JumptoAnoxiaEditField       matlab.ui.control.NumericEditField
        JumptoAnoxiaEditFieldLabel  matlab.ui.control.Label
        EupneicButton               matlab.ui.control.Button
        TableButton                 matlab.ui.control.Button
        RegularityButton            matlab.ui.control.Button
        featureLamp                 matlab.ui.control.Lamp
        RemoveBreathButton          matlab.ui.control.StateButton
        RecalculateFeaturesButton   matlab.ui.control.Button
        AddBreathButton             matlab.ui.control.StateButton
        HRRestoredButton            matlab.ui.control.Button
        SecondaryPlotsButton        matlab.ui.control.Button
        SaveandExportButton         matlab.ui.control.Button
        ApneaButton                 matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        GasOffButton                matlab.ui.control.Button
        GasOnButton                 matlab.ui.control.Button
        PreviousAnoxiaButton        matlab.ui.control.Button
        UploadtxtFileButton         matlab.ui.control.Button
        NextAnoxiaButton            matlab.ui.control.Button
        Piezo                       matlab.ui.control.UIAxes
        ECG                         matlab.ui.control.UIAxes
        Resp                        matlab.ui.control.UIAxes
    end


    properties (Access = public)

      % Labchart Properties   TEST
        rawData
        arData
        sampleRate
        respFlow
        ecg
        piezo
        bodyTemp

      % Event Marking Properties
        time
        timeSegments
        respSegments
        ecgSegments
        tempSegments
        piezoSegments
        envelopeSegments
        percentSegments
        segmentMarkersIndices

        onMarkers
        baselineStart
        offMarkers
        gasOns
        gasOffs
        gasOnComments
        gasOffComments
        finalOnMarker
        finalGasOnComment
        anoxiaDurations
        anoxia

        baselineRespFeatures
        baselineRespPositions
        baselineTable

        baselineHR
        heartRate
        RPeaks
        RIndex
        RTimes
        RMarks
        HRTimes
        HRRestored
        HRRestoredPosition
        timeToHRRestored

        challengeIndex
        firstApnea
        firstApneaPosition
        startOfApneaX
        startOfApneaY
        apneaShift
        timeToApnea
        longestApnea
        numOfApneas

        isGasp
        firstGasp
        firstGaspPosition
        firstGaspX
        firstGaspY
        gaspCount
        gaspTemp
        gaspLatency

        firstEupneic
        firstEupneicPosition
        timeToFirstEupneic
        restore63
        restore63Position
        timeToRestore63
        regularity
        regularityPosition
        timeToRegularity

        timeSpentMoving
        trialTemps

        breathPositions
        plotWindow
        analyzed

        manualBreaths
        addInhale
        addInhalePosition
        addExhale
        addExhalePosition
        breathsRemoved
        removeBreath
        removeBreathPosition
        lineECG
        linePiezo
        
      % Breathmetrics Properties
        dataType
        tempBaselineBM
        baselineBM
        baselineData
        baselineCorrectedRespiration
        postAnoxiaBM
        tempChallengeBM
        challengeBM

        baselineMinVent
        challengeMinVent
        baselineTimes
        challengeTimes
        challengeYs

        transitionBreaths

        File
        tempIncluded
        analyzer
        experimenter
        summaryTable
        rawDataTable
        baselineFeatTable
        tableFig
        baseTable
        anoxiaType
        hypoxiaPC
        gender
        weight
        age
        experimentSetup
        demogCom

        txtUploaded
        CRF
        automated

        challengeAmplitudes
        challengeTimeOfInhalePeaks
        challengeInhaleVols
        challengeExhaleTroughAmplitudes
        challengeTimeOfExhaleTroughs
        abruptPoints
        greaterAmplitudePoints
        bothPoints
        abruptPlot
        GAPlot 
        bothPlot 
        volText
        ampText
        markerLegd

    end
    
    methods (Access = public)
        
        function pointMoved(app,src,~)
            Tag = src.Tag;
            switch Tag
                case 'firstApnea'
                    [~,closestBreath] = min(abs(app.challengeTimes{app.anoxia}...
                        -app.firstApnea.Position(1)));

                    app.firstApnea.Position = [app.challengeTimes{app.anoxia}(closestBreath)...
                        app.challengeYs{app.anoxia}(closestBreath)];
                    drawnow;
                  % Saving the position of the point
                    app.firstApneaPosition(app.anoxia,:) = app.firstApnea.Position;

                  % Adjusting the firstGasp
                    app.firstGasp.Position = [app.challengeTimes{app.anoxia}(closestBreath+1)...
                        app.challengeYs{app.anoxia}(closestBreath+1)];
                    drawnow;

                  % Saving the position of the point
                    app.firstGaspPosition(app.anoxia,:) = app.firstGasp.Position;

                  % Updating measured variables referencing the firstGasp

                     % Calculating the time from gas on to first apnea
                      app.timeToApnea(app.anoxia) = app.firstApneaPosition(app.anoxia,1)...
                          -app.onMarkers(app.anoxia,3);

                     % Calculating the time from first apnea to the first gasp
                      app.gaspLatency(app.anoxia) = app.firstGaspPosition(app.anoxia,1)...
                          -app.firstApneaPosition(app.anoxia,1);

                     % Calculating the time from first apena to return to eupnea
                      app.timeToRestore63(app.anoxia) = app.restore63Position(app.anoxia,1)...
                          -app.firstApneaPosition(app.anoxia,1);

                     % Calculating the time from first apena to restored HR
                      app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)...
                          -app.firstApneaPosition(app.anoxia,1);
                       
                     
                case 'firstGasp'
                    [~,closestBreath] = min(abs(app.challengeTimes{app.anoxia}...
                        -app.firstGasp.Position(1)));

                    app.firstGasp.Position = [app.challengeTimes{app.anoxia}(closestBreath)...
                        app.challengeYs{app.anoxia}(closestBreath)];
                    drawnow;

                  % Saving the position of the point
                    app.firstGaspPosition(app.anoxia,:) = app.firstGasp.Position;

                  % Calculating the time from first apnea to the first gasp
                    app.gaspLatency(app.anoxia) = app.firstGaspPosition(app.anoxia,1)...
                        -app.firstApneaPosition(app.anoxia,1);

                case 'eupnea'
                    [~,closestBreath] = min(abs(app.challengeTimes{app.anoxia}...
                        -app.restore63.Position(1)));

                    app.restore63.Position = [app.challengeTimes{app.anoxia}(closestBreath)...
                        app.challengeYs{app.anoxia}(closestBreath)];
                    drawnow;
                  % Saving the position of the point
                    app.restore63Position(app.anoxia,:) = app.restore63.Position;

                  % Calculating the time from first apena to return to eupnea
                    app.timeToRestore63(app.anoxia) = app.restore63Position(app.anoxia,1)...
                        -app.firstApneaPosition(app.anoxia,1);

                case 'regularity'
                    [~,closestBreath] = min(abs(app.challengeTimes{app.anoxia}...
                        -app.regularity.Position(1)));

                    app.regularity.Position = [app.challengeTimes{app.anoxia}(closestBreath)...
                        app.challengeYs{app.anoxia}(closestBreath)];
                    drawnow;
                   % Saving the position of the point
                    app.regularityPosition(app.anoxia,:) = app.regularity.Position;

                   % Calculating the time from first apena to return to regularity
                    app.timeToRegularity(app.anoxia) = app.regularityPosition(app.anoxia,1)...
                        -app.firstApneaPosition(app.anoxia,1);

                case 'firstEupneic'
                    [~,closestBreath] = min(abs(app.challengeTimes{app.anoxia}...
                        -app.firstEupneic.Position(1)));

                    app.firstEupneic.Position = [app.challengeTimes{app.anoxia}(closestBreath)...
                        app.challengeYs{app.anoxia}(closestBreath)];
                    drawnow;
                   % Saving the position of the point
                    app.firstEupneicPosition(app.anoxia,:) = app.firstEupneic.Position;

                   % Calculating the time from first apena to return to regularity
                    app.timeToFirstEupneic(app.anoxia) = app.firstEupneicPosition(app.anoxia,1)...
                        -app.firstApneaPosition(app.anoxia,1);

                case 'HRRestored'
                    [~,closestBeat] = min(abs(app.RTimes{app.anoxia}...
                        -app.HRRestored.Position(1)));

                    app.HRRestored.Position = [app.RTimes{app.anoxia}(closestBeat)...
                        0];
                    drawnow;
                  % Saving the position of the point
                    app.HRRestoredPosition(app.anoxia,:) = app.HRRestored.Position;

                  % Calculating the time from first apena to restored HR
                    app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)...
                        -app.firstApneaPosition(app.anoxia,1);

                case 'addInhale'
                    inhaleX = round(app.addInhale.Position(1),3);
                    inhaleIndex = find(app.timeSegments(app.anoxia,:)==inhaleX);
                    refBounds = [inhaleIndex-20 inhaleIndex+20];

                    addInhaleY = max(app.respSegments(app.anoxia,refBounds(1):refBounds(2)));
                    addInhaleX = app.timeSegments(app.anoxia,find(app.respSegments(app.anoxia,:)==addInhaleY));
                    addInhaleIndex = find(app.timeSegments(app.anoxia,:) == addInhaleX);

                    app.addInhalePosition{app.anoxia}(app.manualBreaths(app.anoxia),:) = [addInhaleX addInhaleY addInhaleIndex];
                    app.addInhale.Position = [addInhaleX addInhaleY];

                    app.lineECG.Position = [addInhaleX 10; addInhaleX -10];
                    app.linePiezo.Position = [addInhaleX 10;addInhaleX -10];

                 case 'addExhale'
                     
                    exhhaleX = round(app.addExhale.Position(1),3);
                    exhaleIndex = find(app.timeSegments(app.anoxia,:)==exhhaleX);
                    refBounds = [exhaleIndex-20 exhaleIndex+20];

                    addExhaleY = min(app.respSegments(app.anoxia,refBounds(1):refBounds(2)));
                    addExhaleX = app.timeSegments(app.anoxia,find(app.respSegments(app.anoxia,:)==addExhaleY));
                    addExhaleIndex = find(app.timeSegments(app.anoxia,:)== addExhaleX);

                    app.addExhalePosition{app.anoxia}(app.manualBreaths(app.anoxia),:) = [addExhaleX addExhaleY addExhaleIndex];
                    app.addExhale.Position = [addExhaleX addExhaleY];

                case 'removeBreath'
                    [~,closestBreath] = min(abs(app.challengeTimes{app.anoxia}...
                        -app.removeBreath.Position(1)));

                    app.removeBreath.Position = [app.challengeTimes{app.anoxia}(closestBreath)...
                        app.challengeYs{app.anoxia}(closestBreath)];
                    drawnow;
                    % Saving the position of the point
                    app.removeBreathPosition{app.anoxia}(app.breathsRemoved(app.anoxia),:) = [app.removeBreath.Position closestBreath];

                    app.lineECG.Position = [app.removeBreath.Position(1) 10; app.removeBreath.Position(1) -10];
                    app.linePiezo.Position = [app.removeBreath.Position(1) 10; app.removeBreath.Position(1) -10];

            end
        end
    end
    
    methods (Access = private)
        
        function plotTraces(app,status)
            cla(app.Resp) % Clearing Resp axis
            cla(app.ECG) % Clearing ECG axis
            cla(app.Piezo) % Clearing Piezo Axis
            app.Resp.Interactions = panInteraction;
            app.ECG.Interactions = panInteraction;
            app.plotWindow = [app.segmentMarkersIndices(app.anoxia,1)-5,...
                app.segmentMarkersIndices(app.anoxia,1)+5]; % Defining the x-axis bounds of the plot window

         %% Respiration Plot
          % Plotting respiratory data
            hold(app.Resp,'on')
            % axis(app.Resp,'auto y')
            plot(app.Resp,app.timeSegments(app.anoxia,:),app.respSegments(...
                app.anoxia,:),'k')

          % Plotting Gas On Marker
            scatter(app.Resp,app.segmentMarkersIndices(app.anoxia,1), ...
                app.onMarkers(app.anoxia,2),'>','MarkerEdgeColor', ...
                [0 .5 .5],'MarkerFaceColor',[0 .7 .7],'LineWidth',2.5)

          % Plotting Gas Off Marker
            scatter(app.Resp,app.segmentMarkersIndices(app.anoxia,2), ...
                app.offMarkers(app.anoxia,2),'s','MarkerEdgeColor', ...
                [0.6350 0.0780 0.1840],'MarkerFaceColor',[1 0 0],'LineWidth',3)

          % Shifting plot window
            xlim(app.Resp,app.plotWindow)
            hold(app.Resp,'off')

         %% ECG Plot
          % Plotting ecg data
            hold(app.ECG,'on')
            % axis(app.ECG,'auto y')
            plot(app.ECG,app.timeSegments(app.anoxia,:),app.ecgSegments(...
                app.anoxia,:),'Color',[0.6350 0.0780 0.1840])
            
          % Plotting Gas On Markers on ecg
            scatter(app.ECG,app.segmentMarkersIndices(app.anoxia,1), ...
                app.onMarkers(app.anoxia,2),'>','MarkerEdgeColor', ...
                [0 .5 .5],'MarkerFaceColor',[0 .7 .7],'LineWidth',2.5)

          % Plotting Gas Off Markers on ecg
            scatter(app.ECG,app.segmentMarkersIndices(app.anoxia,2), ...
                app.onMarkers(app.anoxia,2),'s','MarkerEdgeColor', ...
                [0.6350 0.0780 0.1840],'MarkerFaceColor',[1 0 0],'LineWidth',3)

          % Shifting plot window
            xlim(app.ECG,app.plotWindow)
            hold(app.ECG,'off')

          %% Piezo Plot
           % Plotting piezo data
            hold(app.Piezo,'on')
            app.Piezo.Units = "normalized";
            axis(app.Piezo,'auto')
            plot(app.Piezo,app.timeSegments(app.anoxia,:),app.piezoSegments(...
                app.anoxia,:),'Color',[0 0.4470 0.7410])

           % Plotting Piezo envelope
           plot(app.Piezo,app.timeSegments(app.anoxia,:),app.envelopeSegments...
               (app.anoxia,:),'k-')
            
           % Plotting Gas On Markers on piezo
            scatter(app.Piezo,app.segmentMarkersIndices(app.anoxia,1), ...
                app.onMarkers(app.anoxia,2),'>','MarkerEdgeColor', ...
                [0 .5 .5],'MarkerFaceColor',[0 .7 .7],'LineWidth',2.5)

           % Plotting Gas Off Markers on piezo
            scatter(app.Piezo,app.segmentMarkersIndices(app.anoxia,2), ...
                app.onMarkers(app.anoxia,2),'s','MarkerEdgeColor', ...
                [0.6350 0.0780 0.1840],'MarkerFaceColor',[1 0 0],'LineWidth',3)

           % Shifting plot window
            xlim(app.Piezo,app.plotWindow)
            hold(app.Piezo,'off')

           % Linking Axes together
            linkaxes([app.Resp,app.ECG,app.Piezo],'x')

            if status == 1

                hold(app.Resp,'on')
               % Draggable point for restored 63%
                app.restore63 = drawpoint(app.Resp,'Position',app.restore63Position(app.anoxia,:),...
                    'Color',[0.466 0.674 0.188],'Tag','eupnea','Label','Return to 63%',...
                    'LabelVisible','hover');

               % Draggable point for regularity
                app.regularity = drawpoint(app.Resp,'Position',app.regularityPosition(app.anoxia,:),...
                    'Color',[0.301 0.745 0.933],'Tag','regularity','Label','Return to Regularity',...
                    'LabelVisible','hover');

               % Draggable point for first eupneic breath
                app.firstEupneic = drawpoint(app.Resp,'Position',app.firstEupneicPosition(app.anoxia,:),...
                    'Color',[0.85 0.325 0.098],'Tag','firstEupneic','Label','First Eupneic Breath',...
                    'LabelVisible','hover');

                addlistener(app.restore63,'ROIMoved',@(src,event) pointMoved(app,src,event));
                addlistener(app.regularity,'ROIMoved',@(src,event) pointMoved(app,src,event));
                addlistener(app.firstEupneic,'ROIMoved',@(src,event) pointMoved(app,src,event));

               % Creating draggable point marking the first gasp
                app.firstGasp = drawpoint(app.Resp,'Position',app.firstGaspPosition(app.anoxia,:), ...
                    'Color',[0.9290 0.6940 0.1250],'Tag','firstGasp','Label','First Gasp',...
                    'LabelVisible','hover');
                addlistener(app.firstGasp,'ROIMoved',@(src,event) pointMoved(app,src,event));

               % Creating draggable point marking the first apnea
                app.firstApnea = drawpoint(app.Resp,'Position',app.firstApneaPosition(app.anoxia,:), ...
                    'Color',[0.6350, 0.0780, 0.1840],'Tag','firstApnea','Label','First Apnea',...
                    'LabelVisible','hover');
                addlistener(app.firstApnea,'ROIMoved',@(src,event) pointMoved(app,src,event));

                hold(app.ECG,'on')
               % Creating draggable point marking the HRRestored
                app.HRRestored = drawpoint(app.ECG,'Position',app.HRRestoredPosition(app.anoxia,:), ...
                    'Color',[0 1 0],'Tag','HRRestored','Label','HR Restored',...
                    'LabelVisible','hover');
                addlistener(app.HRRestored,'ROIMoved',@(src,event) pointMoved(app,src,event));

               % Plotting Marked R-waves
                plot(app.ECG,app.RTimes{app.anoxia},app.ecgSegments(app.anoxia,app.RIndex{app.anoxia}),'kx','Color',[0.7 0.7 0.7])
                hold(app.ECG,'off')

                hold(app.Resp,'on')
                app.abruptPlot = scatter(app.Resp,app.abruptPoints{app.anoxia}(:,1),app.abruptPoints{app.anoxia}(:,2),'or','LineWidth',0.5);
                set(app.abruptPlot,'Visible','off')
                app.GAPlot = scatter(app.Resp,app.greaterAmplitudePoints{app.anoxia}(:,1),app.greaterAmplitudePoints{app.anoxia}(:,2),'^b','LineWidth',0.5);
                set(app.GAPlot,'Visible','off')
                app.bothPlot = scatter(app.Resp,app.bothPoints{app.anoxia}(:,1),app.bothPoints{app.anoxia}(:,2),'om','LineWidth',1.5);
                set(app.bothPlot,'Visible','off')

                app.markerLegd = legend(app.Resp,[app.abruptPlot app.GAPlot app.bothPlot],'More Abrupt','Greater Amplitude','Both');
                app.markerLegd.AutoUpdate = 'off';
                set(app.markerLegd,'Visible','off')

                app.volText = text(app.Resp,app.challengeTimeOfInhalePeaks{app.anoxia},app.challengeAmplitudes{app.anoxia}+0.05,string(app.challengeInhaleVols{app.anoxia}), ...
                    "HorizontalAlignment","center","VerticalAlignment","bottom");
                set(app.volText,'Visible','off')

                app.ampText = text(app.Resp,app.challengeTimeOfInhalePeaks{app.anoxia},app.challengeAmplitudes{app.anoxia}+0.05,string(app.challengeAmplitudes{app.anoxia}), ...
                    "HorizontalAlignment","center","VerticalAlignment","bottom");
                set(app.ampText,'Visible','off')

                plot(app.Resp,app.challengeTimeOfInhalePeaks{app.anoxia},app.challengeAmplitudes{app.anoxia},'xk','LineWidth',1.5,'Color',[0.7 0.7 0.7]);
                plot(app.Resp,app.challengeTimeOfExhaleTroughs{app.anoxia},app.challengeExhaleTroughAmplitudes{app.anoxia},'xk','LineWidth',1.5,'Color',[0.7 0.7 0.7])

            else
            end

        end
        
        function prepData(app)
          %% Patches any NaN elements with the mean of the channel
            app.tempIncluded = 0;
            app.arData = app.rawData;
            app.arData.RespFlow(isnan(app.arData.RespFlow)) = mean( ...
                app.arData.RespFlow,1,'omitnan');
            app.arData.ECG(isnan(app.arData.ECG)) = mean( ...
                app.arData.ECG,1,'omitnan');
            app.arData.PiezoStrain(isnan(app.arData.PiezoStrain)) = mean( ...
                app.arData.PiezoStrain,1,'omitnan');
            if width(app.arData) > 5
                app.arData.BodyTemp(isnan(app.arData.BodyTemp)) = mean( ...
                    app.arData.BodyTemp,1,'omitnan');
                app.tempIncluded = 1;
            end

           % Isolating and transpsoing the data channels
            app.respFlow = (-1)*app.arData.RespFlow';
            app.ecg = app.arData.ECG';
            app.piezo = abs(app.arData.PiezoStrain)';
            if width(app.arData) > 5
                app.bodyTemp = app.arData.BodyTemp';
            end

           % Creating time array to plot in seconds
            app.time = (1:length(app.respFlow)) / app.sampleRate;

          %% Finding Gas on and off markers
           % Finding the indices of when gas is turned on
           if app.tempIncluded
               rawCommentStrings = string(app.rawData.Var6);
           else
               rawCommentStrings = string(app.rawData.Var5);
           end
            commentIndices = find(rawCommentStrings~="");
            commentStrings = erase(rawCommentStrings(rawCommentStrings ~= ""),'#*');
         
           % Finding the comments of when gas is turned off
            offComs = contains(commentStrings,caseInsensitivePattern('off'),'IgnoreCase',false);
            offStrings = commentStrings(offComs);
            tempOffMarkerIndices = commentIndices(offComs);

           % Seperating the "not off" comments with their indices
            notOffStrings = commentStrings(~offComs);
            notOffIndices = commentIndices(~offComs);

            if contains(notOffStrings(1),'g') && ((contains(notOffStrings(1),caseInsensitivePattern('f'))...
                    || contains(notOffStrings(1),caseInsensitivePattern('m')))...
                    || contains(notOffStrings(1),caseInsensitivePattern('p')))
                app.demogCom = notOffStrings(1);
                chooseComs = notOffStrings(2:end);
                notOffIndices = notOffIndices(2:end);
                notOffStrings = notOffStrings(2:end);
            else
                chooseComs = notOffStrings;
            end
            
           % Confirming the gas on comments
            selected = 0;
            while selected == 0
            [onCommentIndices,selected] = listdlg('Name','Comment Selection','PromptString',...
                {'Please select the comments that indicate the start of an anoxia,',...
                ('excluding the final anoxia:'),'',...
                '(Hold the Ctrl key and click the desired comments.)'},'ListString',...
                chooseComs(:,1),'ListSize',[300 325]);
            end
            waitfor(onCommentIndices)
            onMarkersIndices = notOffIndices(onCommentIndices);
            
            tempOnComs = notOffStrings(onCommentIndices);
            containsOn = contains(tempOnComs,caseInsensitivePattern('on'),'IgnoreCase',false);
            containsAnoxia = contains(tempOnComs,caseInsensitivePattern('anoxia'),'IgnoreCase',false);
            onComsContains = or(containsAnoxia,containsOn);
            onComsConfirm = length(tempOnComs) == sum(onComsContains);

            if onComsConfirm == 0
                selected = 0;
                while selected == 0
                    [onCommentIndices,selected] = listdlg('Name','Comment Reselection','PromptString',...
                        {'It appears some of your selections may not be gas on comments.','',...
                        'Please double check your selections.','',...
                        'Please select the comments that indicate the start of an anoxia,',...
                        ('excluding the final anoxia:'),'',...
                        '(Hold the Ctrl key and click the desired comments.)'},'ListString',...
                        notOffStrings(:,1),'ListSize',[300 325]);
                end
                waitfor(onCommentIndices)
                onMarkersIndices = notOffIndices(onCommentIndices);
            end

           % All on markers but the last are passsed on to find gas off
           % comments
            finalOnIndex = onMarkersIndices(end);
            onMarkersIndices = onMarkersIndices(1:(end-1));

            app.finalOnMarker = [finalOnIndex,0,finalOnIndex/1000];

           % Finding the cooresponding gas off comment with the gas on comments
            offCommentIndices = zeros(length(onMarkersIndices),1);
            for on = 1:length(onMarkersIndices)
                difference = tempOffMarkerIndices-onMarkersIndices(on);
                offCommentIndices(on) = find(difference > 0,1);
            end
            offMarkersIndices = tempOffMarkerIndices(offCommentIndices);

           % Saving the on and off comments
            app.gasOnComments = notOffStrings(onCommentIndices);
            app.gasOffComments = offStrings(offCommentIndices);

           % Converting sample time point into seconds
            onTimes = app.time(onMarkersIndices)';
            offTimes = app.time(offMarkersIndices)';

           % Calculating the anoxia durations
            app.anoxiaDurations = offTimes-onTimes;

           % Concatinating marker indices,zeros for plotting, and time in seconds
            app.onMarkers = cat(2,onMarkersIndices, ...
                zeros(length(onMarkersIndices),1),onTimes);
            app.offMarkers = cat(2,offMarkersIndices, ...
                zeros(length(offMarkersIndices),1),offTimes);         

           % Saving the index and time when the baseline period starts for each challenge 
            app.baselineStart = [app.onMarkers(:,1)-90000 app.onMarkers(:,3)-90];
            if sum((app.baselineStart < 0),'all') ~= 0
                app.baselineStart(app.baselineStart(:,1) < 0,1) = 1;
                app.baselineStart(app.baselineStart(:,2) < 0,2) = 0.001;
            end


           % Temperature Before and After Trials
           if app.tempIncluded 
               app.trialTemps = [];
               app.trialTemps(:,1) = app.bodyTemp(onMarkersIndices);

               app.trialTemps(:,2) = app.bodyTemp(offMarkersIndices);
           end

        end
        
        function anoxiaChop(app)
         %% Isolating each hypoxia event
          % 1.5 minutes before the gas on and 3 minutes after the gas on
            preGasOn = 90000; % 1.5 mins before
            postGasOn = 4.5*(60*1000); % 9.5 mins after anoxia
            segmentLength = preGasOn+postGasOn+1;

          % Initializing matrices for the isolated segments and segment
          % markers indices
            app.respSegments = zeros(app.gasOns,segmentLength);
            app.ecgSegments = zeros(app.gasOns,segmentLength);
            app.piezoSegments = zeros(app.gasOns,segmentLength);
            app.envelopeSegments = zeros(app.gasOns,segmentLength);
            app.timeSegments = zeros(app.gasOns,segmentLength);
            app.segmentMarkersIndices = zeros(app.gasOns,2);
            if app.tempIncluded
                app.tempSegments = zeros(app.gasOns,segmentLength);
            end 

            for seg = 1:app.gasOns
              % Segment start and end indices
                segmentStart = app.onMarkers(seg,1)-preGasOn;
                segmentEnd = app.onMarkers(seg,1)+postGasOn;

                if segmentStart < 0
                   % If there is not 90 seconds of baseline, this pads the traces with zeros to compensate.
                    app.baselineCorrectedRespiration = horzcat(...
                        zeros(1,abs(segmentStart)),app.baselineCorrectedRespiration);
                    app.ecg = horzcat(...
                        zeros(1,abs(segmentStart)),app.ecg);
                    app.piezo = horzcat(...
                        zeros(1,abs(segmentStart)),app.piezo);
                    if app.tempIncluded
                        app.bodyTemp = horzcat(...
                        zeros(1,abs(segmentStart)),app.bodyTemp);
                    end
                    
                   % This is adjusting the on and off markers to compensate for the amount of zero padding
                    app.onMarkers(:,1) = app.onMarkers(:,1)+abs(segmentStart);
                    app.onMarkers(:,3) = app.onMarkers(:,3)+(abs(segmentStart)/app.sampleRate);
                    app.offMarkers(:,1) = app.offMarkers(:,1)+abs(segmentStart);
                    app.offMarkers(:,3) = app.offMarkers(:,3)+(abs(segmentStart)/app.sampleRate);
                    
                    segmentEnd = segmentEnd+abs(segmentStart)+1;
                    segmentStart = 1;
                end

              % Assigning the segments into the matrices
                app.respSegments(seg,1:end) = ...
                    app.baselineCorrectedRespiration(segmentStart:segmentEnd);
                app.ecgSegments(seg,1:end) = app.ecg(segmentStart:segmentEnd);
                app.timeSegments(seg,1:end) = app.time(segmentStart:segmentEnd);
                if app.tempIncluded
                    app.tempSegments(seg,1:end) = app.bodyTemp(segmentStart:segmentEnd);
                end

                app.piezoSegments(seg,1:end) = app.piezo(segmentStart:segmentEnd);
                env = movmax(app.piezoSegments(seg,:),100);
                app.envelopeSegments(seg,1:end) = env;

                maxActivity = max(app.envelopeSegments(seg,:));
                app.percentSegments(seg,:) = (app.envelopeSegments(seg,:)./maxActivity).*100;
                
              % Translating the marker indices into terms of the segment
                app.segmentMarkersIndices(seg,1) = app.timeSegments(seg,1)...
                    +preGasOn/app.sampleRate;
                app.segmentMarkersIndices(seg,2) = app.anoxiaDurations(seg)...
                    +app.segmentMarkersIndices(seg,1);

                respAbs = abs(app.respSegments(seg,:));
                respAbsMean = mean(respAbs);

                [~,spike] = find(respAbs>(30*respAbsMean));

                if ~isempty(spike)

                        spikeDiff = diff(spike);
                        spikeContinuous = spikeDiff~=1;

                        if sum(spikeContinuous)==0
                            if length(spike)>100
                                spikeStart = find(flip(respAbs(1:spike(1)))<=respAbsMean,1,'first');
                                startIndex = spike(1)-(spikeStart-1);

                                spikeEnd = find(respAbs(spike(end):end)<=respAbsMean,1,'first');
                                endIndex = spike(end)+(spikeEnd-1);

                                spikeIndex = [startIndex endIndex];
                                patchLength = diff(spikeIndex)+1;
                                patch = respAbsMean*ones(patchLength,1);
                                app.respSegments(seg,spikeIndex(1):spikeIndex(2)) = patch;
                            else
                                spikeIndex = [spike(1)-1 spike(end)+3];
                                spikeY = app.respSegments(seg,spikeIndex(1):spikeIndex(2));
                                patchLength = diff(spikeIndex)+1;
                                patch = linspace(spikeY(1),spikeY(end),patchLength);
                                app.respSegments(seg,spikeIndex(1):spikeIndex(2)) = patch;
                            end
                        else
                            spikeIndices = [0,find(spikeContinuous),size(spike,2)]+1;

                            for s = 1:(size(spikeIndices,2)-1)
                                spikeSegment = spike(spikeIndices(s):(spikeIndices(s+1)-1));

                                if length(spikeSegment)>100
                                    spikeStart = find(flip(respAbs(1:spikeSegment(1)))<=respAbsMean,1,'first');
                                    startIndex = spikeSegment(1)-(spikeStart-1);

                                    spikeEnd = find(respAbs(spikeSegment(end):end)<=respAbsMean,1,'first');
                                    endIndex = spikeSegment(end)+(spikeEnd-1);

                                    spikeIndex = [startIndex endIndex];
                                    patchLength = diff(spikeIndex)+1;
                                    patch = respAbsMean*ones(patchLength,1);
                                    app.respSegments(seg,spikeIndex(1):spikeIndex(2)) = patch;
                                else
                                    spikeIndex = [spikeSegment(1)-1 spikeSegment(end)+3];
                                    spikeY = app.respSegments(seg,spikeIndex(1):spikeIndex(2));
                                    patchLength = diff(spikeIndex)+1;
                                    patch = linspace(spikeY(1),spikeY(end),patchLength);
                                    app.respSegments(seg,spikeIndex(1):spikeIndex(2)) = patch;
                                end

                            end

                        end
                end

                drift = medfilt1(app.respSegments(seg,:),1500);
                app.respSegments(seg,:) = app.respSegments(seg,:)-drift;
            end
        end
        
        function findRestoration(app)
          %% Respiration Restoration
           % Finding breath index to isolate the anoxia and post-anoxia breaths
            postAnoxicBreathIndex = find(app.challengeBM(app.anoxia).inhaleOnsets > ...
                (app.anoxiaDurations(app.anoxia))*app.sampleRate,1);

           % Calcualting the standard deviation of the baseline Inter-breath interval
            baselineIBIstd = std(diff(app.baselineBM(app.anoxia).inhaleOnsets));

           % Calculating one time constant percentage (OTCP) of Inter-breath Interval
            oneTimeConstant = 1-(1/(exp(1)));
            OTCPInterBreathInt = 1000/(oneTimeConstant*app.baselineRespFeatures(app.anoxia,11));

           % Sliding window of breaths
            breathWindowSize = 5;
            numBreaths = length(app.challengeBM(app.anoxia).inhalePeaks(postAnoxicBreathIndex:end));
            numBreathWindows = numBreaths-(breathWindowSize-1);

           % Intializing for the sliding window
            featIntervals = zeros(numBreathWindows*2,breathWindowSize-1);
            featIntervalsStd = zeros(numBreathWindows,2);
            featIntIndex = 1;
            intStdIndex = 1;

            for breathWindowSlide = postAnoxicBreathIndex:numBreathWindows

               % Index of breaths in window
                windowBreaths = [breathWindowSlide breathWindowSlide+(breathWindowSize-1)];

               % Intervals of all Four Features (Inhale Onset, Inhale Peak, Exhale Onset, Exhale Trough.
               % Inhale Onset Interval
                featIntervals(featIntIndex,:) = diff(app.challengeBM(app.anoxia).inhaleOnsets...
                    (windowBreaths(1):windowBreaths(2)));
                featIntervalsStd(intStdIndex,1) = std(featIntervals(featIntIndex,:));

               % Inhale Peak Interval
                featIntervals(featIntIndex+1,:) = diff(app.challengeBM(app.anoxia).inhalePeaks...
                    (windowBreaths(1):windowBreaths(2)));
                featIntervalsStd(intStdIndex,2) = std(featIntervals(featIntIndex+1,:));

                featIntIndex = featIntIndex+2;
                intStdIndex = intStdIndex+1;
            end

           % All Four Features IBI are less than or equal to OTCP baseline IBI
            logicalFeatInt = featIntervals <= OTCPInterBreathInt;
            logicalFeatIntSum = sum(reshape(sum(logicalFeatInt,2),2,numBreathWindows),1)';
            restoredBreathIBI = postAnoxicBreathIndex+find(logicalFeatIntSum==((breathWindowSize-1)*2),1,'first')-1;
            app.transitionBreaths(app.anoxia,2) = restoredBreathIBI;

            logicalFeatStd = featIntervalsStd <= baselineIBIstd;
            logicalFeatStdSum = sum(logicalFeatStd,2);
            restoredBreathStd = postAnoxicBreathIndex+find(logicalFeatStdSum==2,1,'first')-1;
            app.transitionBreaths(app.anoxia,3) = restoredBreathStd;

           % Finding the index of the Inhale Onset of restored IBI
            restoredBreathIndex = app.challengeBM(app.anoxia).inhaleOnsets(restoredBreathIBI)+...
                (app.challengeIndex(app.anoxia)-1);

            restoredBreathIndexStd = app.challengeBM(app.anoxia).inhaleOnsets(restoredBreathStd)+...
                (app.challengeIndex(app.anoxia)-1);

            insertPoint = min(app.transitionBreaths(app.anoxia,2:3))-2;
            restoredBreathIndexEupneic = app.challengeBM(app.anoxia).inhaleOnsets(insertPoint)+...
                (app.challengeIndex(app.anoxia)-1);

          %% Heart rate restoration
           % Calculating one time constant of baselin heart rate
            OTCPHeartRate = oneTimeConstant*app.baselineHR(app.anoxia);

            [~,gasOffTimeIndex] = min(abs(app.HRTimes{app.anoxia}-app.offMarkers(app.anoxia,3)));

            postAnoxiaHR = app.heartRate{app.anoxia}(gasOffTimeIndex:end);
            postAnoxiaHRTimes = app.HRTimes{app.anoxia}(gasOffTimeIndex:end);

            [~,minK] = mink(postAnoxiaHR,3);

            postMinHR = postAnoxiaHR(minK(1):end);
            postMinHRTimes = postAnoxiaHRTimes(minK(1):end);

            timeBtwMeasures = diff(postMinHRTimes);
            fiveBreathDuration = 5*(1/app.baselineRespFeatures(app.anoxia,11));

            numHRReadings = length(postMinHR);
            cumulativeSum = 0;

            % Loop through the time intervals to find start and end indexes
            for i = 1:(numHRReadings-1)
                for r = i:(numHRReadings-1)
                    cumulativeSum = cumulativeSum + timeBtwMeasures(r);
                    if cumulativeSum >= fiveBreathDuration
                        break
                    end
                end
                cumulativeSum = 0;
                HRCheck = all((postMinHR(i:r)>=OTCPHeartRate)&...
                    (postMinHR(i:r)<=(app.baselineHR(app.anoxia)+175)));
                if HRCheck==1
                    restoredHRTime = postMinHRTimes(i);
                    app.HRRestoredPosition(app.anoxia,:) = [restoredHRTime 0];
                    break
                end
            end

            % Draggable point for HR Restored
            app.HRRestored = drawpoint(app.ECG,'Position',app.HRRestoredPosition(app.anoxia,:),...
                'Color',[0 1 0],'Tag','HRRestored','Label','Heart Rate Restored',...
                'LabelVisible','hover');
            addlistener(app.HRRestored,'ROIMoved',@(src,event) pointMoved(app,src,event));

           % Defining and saving position of restored Eupnea
            app.restore63Position(app.anoxia,:) = [app.timeSegments(app.anoxia,restoredBreathIndex)...
                app.respSegments(app.anoxia,restoredBreathIndex)];

            app.regularityPosition(app.anoxia,:) = [app.timeSegments(app.anoxia,restoredBreathIndexStd)...
                app.respSegments(app.anoxia,restoredBreathIndexStd)];

            app.firstEupneicPosition(app.anoxia,:) = [app.timeSegments(app.anoxia,restoredBreathIndexEupneic)...
                app.respSegments(app.anoxia,restoredBreathIndexEupneic)];

          % Draggable point for restored 63%
            app.restore63 = drawpoint(app.Resp,'Position',app.restore63Position(app.anoxia,:),...
                'Color',[0.466 0.674 0.188],'Tag','eupnea','Label','Return to 63%',...
                'LabelVisible','hover');

           % Draggable point for regularity 
            app.regularity = drawpoint(app.Resp,'Position',app.regularityPosition(app.anoxia,:),...
                'Color',[0.301 0.745 0.933],'Tag','regularity','Label','Return to Regularity',...
                'LabelVisible','hover');
            
           % Draggable point for first eupneic breath
            app.firstEupneic = drawpoint(app.Resp,'Position',app.firstEupneicPosition(app.anoxia,:),...
                'Color',[0.85 0.325 0.098],'Tag','firstEupneic','Label','First Eupneic Breath',...
                'LabelVisible','hover');

            addlistener(app.restore63,'ROIMoved',@(src,event) pointMoved(app,src,event));
            addlistener(app.regularity,'ROIMoved',@(src,event) pointMoved(app,src,event));
            addlistener(app.firstEupneic,'ROIMoved',@(src,event) pointMoved(app,src,event));

        end
        
        function findApneas(app)
          %% Finding the start of the first apena
            app.apneaShift = 1; 
             
           % Calculating the Two-Breath interval from baseline repiration
            twoBreath = 2*mean(diff(app.baselineBM(app.anoxia).inhaleOnsets));

           % Calculating the inter-breath interval (IBI) for the challenge respiration
            IBIchallenge = diff(app.challengeBM(app.anoxia).inhaleOnsets);

           % Finding the first nFirst IBI's greater than the two-breath interval
            nFirst = 4;
            intervalCheck = IBIchallenge > twoBreath;
            halfSecondCheck = IBIchallenge >= 500;
            [~,firstApneaMarks] = find(and(intervalCheck,halfSecondCheck),nFirst);

           % Check if the proceeding IBI's remain the same or increase to confirm the apena is genuine and not due to distription or noise
            removeMarks = zeros(1,nFirst); 
            for IBIcheck = 1:nFirst
                proceeding = IBIchallenge(firstApneaMarks(IBIcheck)+1:firstApneaMarks(IBIcheck)+2);

                if (sum(proceeding > twoBreath))==0
                    removeMarks(IBIcheck) = IBIcheck;
                end
            end

            if length(nonzeros(removeMarks)) ~= nFirst
                firstApneaMarks(nonzeros(removeMarks)) = [];
            end

           % The index of the last breath before the apnea 
            app.transitionBreaths(app.anoxia,1) = firstApneaMarks(1);

           % Indices of the 3 first potential first apneas
            firstApneaIndices = app.challengeBM(app.anoxia).inhaleOnsets(firstApneaMarks)+...
                (app.challengeIndex(app.anoxia)-1);

           % Times of the 3 first potential first apneas
            firstApneaTimes = app.timeSegments(app.anoxia,firstApneaIndices);

           % Finding the position of this point to plot on the trace
            app.startOfApneaX = firstApneaTimes;
            app.startOfApneaY = app.respSegments(app.anoxia,firstApneaIndices);
            app.firstApneaPosition(app.anoxia,:) = [app.startOfApneaX(1) app.startOfApneaY(1)];

           % Creating draggable point marking the first apnea
            app.firstApnea = drawpoint(app.Resp,'Position',app.firstApneaPosition(app.anoxia,:), ...
                'Color',[0.6350, 0.0780, 0.1840],'Tag','firstApnea','Label','First Apnea',...
                'LabelVisible','hover');
            addlistener(app.firstApnea,'ROIMoved',@(src,event) pointMoved(app,src,event));

           % Calculating the time from gas on to first apnea
            app.timeToApnea(app.anoxia) = firstApneaTimes(1)-app.onMarkers(app.anoxia,3);

           % Calculating the time from first apena to return to eupnea
            app.timeToRestore63(app.anoxia) = app.restore63Position(app.anoxia,1)...
                -app.firstApneaPosition(app.anoxia,1);

           % Calculating the time from first apena to return to eupnea
            app.timeToRegularity(app.anoxia) = app.regularityPosition(app.anoxia,1)...
                -app.firstApneaPosition(app.anoxia,1);

           % Calculating the time from first apena to first eupneic breath
            app.timeToFirstEupneic(app.anoxia) = app.firstEupneicPosition(app.anoxia,1)...
                -app.firstApneaPosition(app.anoxia,1);

           % Calculating the time from first apena to return to HR Restored
            app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)-...
                app.firstApneaPosition(app.anoxia,1);

          %% Calculating the gasp latency
            firstGaspMark = firstApneaMarks+1;
            firstGaspIndex = app.challengeBM(app.anoxia).inhaleOnsets(firstGaspMark)+...
                app.challengeIndex(app.anoxia);
            firstGaspTime = app.timeSegments(app.anoxia,firstGaspIndex);

            % Finding the position of this point to plot on the trace
            app.firstGaspX = app.timeSegments(app.anoxia,firstGaspIndex);
            app.firstGaspY = app.respSegments(app.anoxia,firstGaspIndex);
            app.firstGaspPosition(app.anoxia,:) = [app.firstGaspX(1) app.firstGaspY(1)];

           % Creating draggable point marking the first gasp
            app.firstGasp = drawpoint(app.Resp,'Position',app.firstGaspPosition(app.anoxia,:), ...
                'Color',[0.9290 0.6940 0.1250],'Tag','firstGasp','Label','First Gasp',...
                'LabelVisible','hover');
            addlistener(app.firstGasp,'ROIMoved',@(src,event) pointMoved(app,src,event));

           % Calculating the time from first apnea to the first gasp
            app.gaspLatency(app.anoxia) = firstGaspTime(1)-firstApneaTimes(1);

          %% Calculating apnea measurements
           % Calculating longest apnea as the longest interval between
           % onsets between first apnea and return to eupnea
            app.longestApnea(app.anoxia) = max(IBIchallenge(firstApneaMarks(1)+1:...
                app.transitionBreaths(app.anoxia,2)+1))./app.sampleRate;

           % Calculating number of apneas as the number of intervals
           % between breaths greater than the two breath duration
            app.numOfApneas(app.anoxia) = sum(IBIchallenge(firstApneaMarks(1)+1:...
                (app.transitionBreaths(app.anoxia,2)+1)> twoBreath));

          % Marking this anoxia trial as analyzed
            app.analyzed(app.anoxia) = 1;

        end
        
        function estimateFeatures(app,manual)
            if ~manual

              %% Heart Rate Features
               % Calculating rate rate from ECG
                normMagnitude = abs(normalize(app.ecgSegments(app.anoxia,:)));
                magnitudeSquared = normMagnitude.^2;
                minPeakHeight = 2.5*mean(magnitudeSquared);
                [app.RPeaks{app.anoxia},app.RIndex{app.anoxia}] = findpeaks(magnitudeSquared,...
                    'MinPeakHeight',minPeakHeight,'MinPeakDistance',86);

                app.RTimes{app.anoxia} = app.timeSegments(app.anoxia,app.RIndex{app.anoxia});
                RRInterval = diff(app.RTimes{app.anoxia});
                app.heartRate{app.anoxia} = 60./RRInterval;
                app.HRTimes{app.anoxia} = (app.RTimes{app.anoxia}(1:(end-1)));

                lastRTimeDiff = app.timeSegments(app.anoxia,end)-app.RTimes{app.anoxia}(end);

                tolerance = 0.5;

                if lastRTimeDiff > tolerance
                        notice = uifigure;
                        uialert(notice,'Calculating Heart Rate from unstable ECG Signal, this will take a few moments...', ...
                            'One Moment Please...','Icon','info');
                        app.RIndex{app.anoxia} = islocalmax(magnitudeSquared,'MinSeparation',86);
                        app.RTimes{app.anoxia} = app.timeSegments(app.anoxia,app.RIndex{app.anoxia});
                        RRInterval = diff(app.RTimes{app.anoxia});
                        app.heartRate{app.anoxia} = 60./RRInterval;
                        app.HRTimes{app.anoxia} = (app.RTimes{app.anoxia}(1:(end-1)));
                        delete(notice)
                end

                if isempty(app.RMarks)
                   % Plotting Marked R-waves
                    hold(app.ECG,'on')
                    app.RMarks = plot(app.ECG,app.RTimes{app.anoxia},app.ecgSegments(app.anoxia,app.RIndex{app.anoxia}), ...
                        'kx','Color',[0.5 0.5 0.5]);
                    hold(app.ECG,'off')
                end

               % Calculating the average heart rate of the baseline period
                [~,gasOffTimeIndex] = min(abs(app.HRTimes{app.anoxia}-app.onMarkers(app.anoxia,3)));
                app.baselineHR(app.anoxia) = mean(app.heartRate{app.anoxia}(1:gasOffTimeIndex));

              %% Respiration Features
                % Calculating baseline respiration features
                baselineResp = app.respSegments(app.anoxia,1:90000); % 90 s of baseline respiration
                app.baselineBM(app.anoxia) = ARSBreathmetrics(baselineResp,app.sampleRate, app.dataType,0);
                app.baselineBM(app.anoxia).estimateAllFeatures(0,'done',1,0);

               % Converting secondary features from cell to double
                baselineSecondaryFeatures = cell2mat(values(app.baselineBM(app.anoxia).secondaryFeatures));
                app.baselineRespFeatures(app.anoxia,:) = baselineSecondaryFeatures;

               % Segmenting the respirtory trace after the gas on
                challengeResp = app.respSegments(app.anoxia,...
                    app.challengeIndex(app.anoxia):end);

                app.challengeBM(app.anoxia) = ARSBreathmetrics(challengeResp,app.sampleRate,app.dataType,0);
                app.challengeBM(app.anoxia).estimateAllFeatures(0,'done',1,0);

            end
            
           % Plotting Marked R-waves
            hold(app.ECG,'on')
            plot(app.ECG,app.RTimes{app.anoxia},app.ecgSegments(app.anoxia,app.RIndex{app.anoxia}), ...
                'kx','Color',[0.5 0.5 0.5])
            hold(app.ECG,'off')

          %% Calculating instantaneous minute ventilation
           % Finding the corresponding times for each breath
            app.baselineTimes{app.anoxia} = (app.baselineBM(app.anoxia).inhaleOnsets + ...
                app.baselineStart(app.anoxia,1))./app.sampleRate;
           % Calculating the baseline Respiratory Rate
            % (60*sample rate)/(number of samples between breaths)
            baselineRR = (60*app.sampleRate)./(diff(app.baselineBM(app.anoxia).inhaleOnsets));
           % Calculating Tidal Volume for baseline respiration
            baselineTV = app.baselineBM(app.anoxia).inhaleVolumes + app.baselineBM(app.anoxia).exhaleVolumes;
           % Calculating the minute ventilation
            % (Respiratory Rate * Tidal Volume) 
            app.baselineMinVent{app.anoxia} = baselineRR.*baselineTV(1:(end-1));

           % Finding the corresponding times and y-positions for each breath
            challengeIndices = app.challengeBM(app.anoxia).inhaleOnsets+(app.challengeIndex(app.anoxia)-1);
            app.challengeTimes{app.anoxia} = app.timeSegments(app.anoxia,challengeIndices);
            app.challengeYs{app.anoxia} = app.respSegments(app.anoxia,challengeIndices);
            
           % Calculating the baseline Respiratory Rate
           % (sample rate/(number of samples between breaths)
            challengeRR = (60*app.sampleRate)./(diff(app.challengeBM(app.anoxia).inhaleOnsets));
           % Calculating Tidal Volume for baseline respiration
            challengeTV = app.challengeBM(app.anoxia).inhaleVolumes + app.challengeBM(app.anoxia).exhaleVolumes;
           % Calculating the minute ventilation 
           % (Respiratory Rate * Tidal Volume)
            app.challengeMinVent{app.anoxia} = challengeRR.*challengeTV(1:(end-1));

          %% Complying raw data 
           % Organizing baseline variables for raw data table
           timeOfInhaleOnsetsB = app.baselineTimes{app.anoxia}';                        
            timeOfInhalePeaksB = ((app.baselineBM(app.anoxia).inhalePeaks + ...                        
                app.baselineStart(app.anoxia,1))./app.sampleRate)';
            inhaleTimeToPeakB = app.baselineBM(app.anoxia).inhaleDurations';
            inhalePeakAmplitudeB = app.respSegments(app.anoxia,app.baselineBM(app.anoxia).inhalePeaks)';
            timeOfExhaleOnsetsB = ((app.baselineBM(app.anoxia).exhaleOnsets + ...
                app.baselineStart(app.anoxia,1))./app.sampleRate)';
            timeOfExhaleTroughsB = ((app.baselineBM(app.anoxia).exhaleTroughs + ...
                app.baselineStart(app.anoxia,1))./app.sampleRate)';
            exhaleTimeToTroughB = app.baselineBM(app.anoxia).exhaleDurations';
            exhaleTroughAmplitudeB = app.respSegments(app.anoxia,app.baselineBM(app.anoxia).exhaleTroughs)';
            respRateB = vertcat(baselineRR',NaN);
            TtotB = vertcat((diff(app.baselineBM(app.anoxia).inhaleOnsets)./app.sampleRate)',NaN);
            inhaleVolsB = app.baselineBM(app.anoxia).inhaleVolumes';
            exhaleVolsB = app.baselineBM(app.anoxia).exhaleVolumes';
            tidalVolsB = inhaleVolsB+exhaleVolsB;
            minVentB = vertcat(app.baselineMinVent{app.anoxia}',NaN);
            piezoForBreathB = app.envelopeSegments(app.anoxia,app.baselineBM(app.anoxia).inhaleOnsets)';
            conditionB = ones(length(inhaleVolsB),1);
            numWInCondB = (1:length(inhaleVolsB))';
            
           % Organizing challenge variables for raw data table
            timeOfInhaleOnsetsC = app.challengeTimes{app.anoxia}';  
            timeOfInhalePeaksC = ((app.challengeBM(app.anoxia).inhalePeaks + ...
                (app.onMarkers(app.anoxia,1)-1))./app.sampleRate)';
            inhaleTimeToPeakC = app.challengeBM(app.anoxia).inhaleDurations';
            inhalePeakAmplitudeC = app.respSegments(app.anoxia,app.challengeBM(app.anoxia).inhalePeaks+90000)';
            timeOfExhaleOnsetsC = ((app.challengeBM(app.anoxia).exhaleOnsets + ...
                (app.onMarkers(app.anoxia,1)-1))./app.sampleRate)';
            timeOfExhaleTroughsC = ((app.challengeBM(app.anoxia).exhaleTroughs + ...
                (app.onMarkers(app.anoxia,1)-1))./app.sampleRate)';
            exhaleTimeToTroughC = app.challengeBM(app.anoxia).exhaleDurations';
            exhaleTroughAmplitudeC = app.respSegments(app.anoxia,app.challengeBM(app.anoxia).exhaleTroughs+90000)';
            respRateC = vertcat(challengeRR',NaN);
            TtotC = vertcat((diff(app.challengeBM(app.anoxia).inhaleOnsets)./app.sampleRate)',NaN);
            inhaleVolsC = app.challengeBM(app.anoxia).inhaleVolumes';
            exhaleVolsC = app.challengeBM(app.anoxia).exhaleVolumes';
            tidalVolsC = inhaleVolsC+exhaleVolsC;
            minVentC = vertcat(app.challengeMinVent{app.anoxia}',NaN);
            conditionC = 2*ones(length(inhaleVolsC),1);
            numWInCondC = (1:length(inhaleVolsC))';
            anoxicEnd = find(app.challengeTimes{app.anoxia}>(app.offMarkers(app.anoxia,3)),1,"first");
            piezoForBreathC = app.envelopeSegments(app.anoxia,app.challengeBM(app.anoxia).inhaleOnsets)';
            conditionC(anoxicEnd:end) = 3; 
            numWInCondC(anoxicEnd:end) = (1:(length(numWInCondC(anoxicEnd:end))))';

           % "Connecting" the two breathmetric estimates in the minVent, respRate, and Ttot
            respRateB(end) = 60/(timeOfInhaleOnsetsC(1)-timeOfInhaleOnsetsB(end));
            TtotB(end) = timeOfInhaleOnsetsC(1)-timeOfInhaleOnsetsB(end);
            minVentB(end) = respRateB(end)*tidalVolsB(end);

           % Concatinating baseline and challenge variables
            timeOfInhaleOnsets = vertcat(timeOfInhaleOnsetsB,timeOfInhaleOnsetsC);
            timeOfInhalePeaks = vertcat(timeOfInhalePeaksB,timeOfInhalePeaksC);
            inhaleTimeToPeak = vertcat(inhaleTimeToPeakB,inhaleTimeToPeakC);
            inhalePeakAmplitudes = vertcat(inhalePeakAmplitudeB,inhalePeakAmplitudeC);
            timeOfExhaleOnsets = vertcat(timeOfExhaleOnsetsB,timeOfExhaleOnsetsC);
            timeOfExhaleTroughs = vertcat(timeOfExhaleTroughsB,timeOfExhaleTroughsC);
            exhaleTimeToTrough = vertcat(exhaleTimeToTroughB,exhaleTimeToTroughC);
            exhaleTroughAmplitudes = vertcat(exhaleTroughAmplitudeB,exhaleTroughAmplitudeC);
            respRate = vertcat(respRateB,respRateC);
            Ttot = vertcat(TtotB,TtotC);
            inhaleVols = vertcat(inhaleVolsB,inhaleVolsC);
            exhaleVols = vertcat(exhaleVolsB,exhaleVolsC);
            tidalVols = vertcat(tidalVolsB,tidalVolsC);
            minVent = vertcat(minVentB,minVentC);
            piezoForBreath = vertcat(piezoForBreathB,piezoForBreathC);
            condition = vertcat(conditionB,conditionC);
            numWInCond = vertcat(numWInCondB,numWInCondC);
            ID = erase(app.File,'.txt');
            animalID = char(repmat({ID},length(timeOfInhalePeaks),1));
            hypoxiaPreCond = ones(length(timeOfInhalePeaks),1);
            trialNum = app.anoxia*ones(length(timeOfInhalePeaks),1);
            Reader = str2double(app.analyzer)*ones(length(timeOfInhalePeaks),1);
            ExperimentSetup = ones(length(timeOfInhalePeaks),1);
            Experimenter = ones(length(timeOfInhalePeaks),1);
            AnoxiaType = ones(length(timeOfInhalePeaks),1);
            Gender = ones(length(timeOfInhalePeaks),1);
            Weight = ones(length(timeOfInhalePeaks),1);
            Age = ones(length(timeOfInhalePeaks),1);

           % Nearest HR reading for each breath
            HRForBreath = zeros(length(timeOfInhaleOnsets),1);

            for numBreaths = 1:length(timeOfInhaleOnsets)
                target = timeOfInhaleOnsets(numBreaths);
                HRIndex = find(app.HRTimes{app.anoxia}<=target,1,'last');
                if isempty(HRIndex)
                    HRIndex = 1;
                end
                HRForBreath(numBreaths) = app.heartRate{app.anoxia}(HRIndex);
            end

            if app.tempIncluded
                tempForBreath = vertcat(app.tempSegments(app.baselineBM(app.anoxia).inhaleOnsets)',...
                    app.tempSegments(app.challengeBM(app.anoxia).inhaleOnsets)');

                % Assembling all data into a table with temperature data
                app.rawDataTable{app.anoxia} = table(Reader,ExperimentSetup,Experimenter,animalID,Gender,Weight,Age,hypoxiaPreCond,AnoxiaType,trialNum,numWInCond,...
                    HRForBreath,tempForBreath,timeOfInhaleOnsets,timeOfInhalePeaks,inhaleTimeToPeak,...
                    inhalePeakAmplitudes,timeOfExhaleOnsets,timeOfExhaleTroughs,exhaleTimeToTrough,exhaleTroughAmplitudes, ...
                    respRate,Ttot,inhaleVols,exhaleVols,tidalVols,minVent,piezoForBreath,condition);
            else
                % Assembling all data into a table without Tempature data
                app.rawDataTable{app.anoxia} = table(Reader,ExperimentSetup,Experimenter,animalID,Gender,Weight,Age,hypoxiaPreCond,AnoxiaType,trialNum,numWInCond,...
                    HRForBreath,timeOfInhaleOnsets,timeOfInhalePeaks,inhaleTimeToPeak,...
                    inhalePeakAmplitudes,timeOfExhaleOnsets,timeOfExhaleTroughs,exhaleTimeToTrough,exhaleTroughAmplitudes, ...
                    respRate,Ttot,inhaleVols,exhaleVols,tidalVols,minVent,piezoForBreath,condition);
            end

            RR = app.baselineRespFeatures(app.anoxia,11)*60; 
            IBI = mean(diff(app.baselineBM(app.anoxia).inhaleOnsets))/1000;
            Amp = mean(inhalePeakAmplitudeB); 
            Vol = mean(inhaleVolsB);
            HR = app.baselineHR(app.anoxia);

            app.baselineFeatTable(:,(app.anoxia+1)) = {RR;IBI;Amp;Vol;HR};

            if isgraphics(app.tableFig)
                app.baseTable.Data(:,(app.anoxia+1)) = {RR;IBI;Amp;Vol;HR};
            end
            
        end
        
        function findGasps(app)
          %% "Measuring" Potential Gasps

           % Finding indices of gas on, first apnea, and eupnea, start in rawDataTable
            endOfBaseline = find(app.rawDataTable{app.anoxia}.condition==2,1,'first');
            apneaStart = find(app.rawDataTable{app.anoxia}.timeOfInhaleOnsets==app.firstApneaPosition(app.anoxia,1))+1;
            eupneaStart = find(app.rawDataTable{app.anoxia}.timeOfInhaleOnsets==app.restore63Position(app.anoxia,1))-1;

           % Isolating the baseline and challenge raw data
            baselineAmplitudes = app.rawDataTable{app.anoxia}.inhalePeakAmplitudes(1:endOfBaseline);
            baselineTimeToPeak = app.rawDataTable{app.anoxia}.inhaleTimeToPeak(1:endOfBaseline);
            
            app.challengeAmplitudes{app.anoxia} = app.rawDataTable{app.anoxia}.inhalePeakAmplitudes(apneaStart:eupneaStart);
            challengeTimeToPeak = app.rawDataTable{app.anoxia}.inhaleTimeToPeak(apneaStart:eupneaStart);
            app.challengeTimeOfInhalePeaks{app.anoxia} = app.rawDataTable{app.anoxia}.timeOfInhalePeaks(apneaStart:eupneaStart);
            app.challengeInhaleVols{app.anoxia} = app.rawDataTable{app.anoxia}.inhaleVols(apneaStart:eupneaStart);

            app.challengeTimeOfExhaleTroughs{app.anoxia} = app.rawDataTable{app.anoxia}.timeOfExhaleTroughs(apneaStart:eupneaStart);
            app.challengeExhaleTroughAmplitudes{app.anoxia} = app.rawDataTable{app.anoxia}.exhaleTroughAmplitudes(apneaStart:eupneaStart);

           % Defining the mean inhale peak amplitude from baseline breaths
            avgAmplitudeB = mean(baselineAmplitudes);

           % Calculating the mean inhalation slope of the baseline breaths (inhale amplitude/time to peak) (Δy/Δt)
            avgInhaleSlopeB = mean(baselineAmplitudes./baselineTimeToPeak);

           % Calculating the inhalation slope of the challenge breaths (inhale amplitude/time to peak) (Δy/Δt)
            inhaleSlopeC = app.challengeAmplitudes{app.anoxia}./challengeTimeToPeak;

           % A gasp will be sharp, abrupt and have greater amplitude than
            abrupt = inhaleSlopeC > avgInhaleSlopeB;
            greaterAmplitude = app.challengeAmplitudes{app.anoxia} > avgAmplitudeB;

            sum = abrupt+greaterAmplitude;
            gasps = sum==2;

            app.abruptPoints{app.anoxia} = horzcat(app.challengeTimeOfInhalePeaks{app.anoxia}(abrupt),(0.5*app.challengeAmplitudes{app.anoxia}(abrupt)));
            app.greaterAmplitudePoints{app.anoxia} = horzcat(app.challengeTimeOfInhalePeaks{app.anoxia}(greaterAmplitude),(0.6*app.challengeAmplitudes{app.anoxia}(greaterAmplitude)));
            app.bothPoints{app.anoxia} = horzcat(app.challengeTimeOfInhalePeaks{app.anoxia}(gasps),(app.challengeAmplitudes{app.anoxia}(gasps)));

            hold(app.Resp,'on')
            app.abruptPlot = scatter(app.Resp,app.abruptPoints{app.anoxia}(:,1),app.abruptPoints{app.anoxia}(:,2),'or','LineWidth',0.5);
            set(app.abruptPlot,'Visible','off')
            app.GAPlot = scatter(app.Resp,app.greaterAmplitudePoints{app.anoxia}(:,1),app.greaterAmplitudePoints{app.anoxia}(:,2),'^b','LineWidth',0.5);
            set(app.GAPlot,'Visible','off')
            app.bothPlot = scatter(app.Resp,app.bothPoints{app.anoxia}(:,1),app.bothPoints{app.anoxia}(:,2),'om','LineWidth',1.5);
            set(app.bothPlot,'Visible','off')

            app.markerLegd = legend(app.Resp,[app.abruptPlot app.GAPlot app.bothPlot],'More Abrupt','Greater Amplitude','Both');
            app.markerLegd.AutoUpdate = 'off';
            set(app.markerLegd,'Visible','off')
            
            app.volText = text(app.Resp,app.challengeTimeOfInhalePeaks{app.anoxia},app.challengeAmplitudes{app.anoxia}+0.05,string(app.challengeInhaleVols{app.anoxia}), ...
                "HorizontalAlignment","center","VerticalAlignment","bottom");
            set(app.volText,'Visible','off')

            app.ampText = text(app.Resp,app.challengeTimeOfInhalePeaks{app.anoxia},app.challengeAmplitudes{app.anoxia}+0.05,string(app.challengeAmplitudes{app.anoxia}), ...
                "HorizontalAlignment","center","VerticalAlignment","bottom");
            set(app.ampText,'Visible','off')

            plot(app.Resp,app.challengeTimeOfInhalePeaks{app.anoxia},app.challengeAmplitudes{app.anoxia},'xk','LineWidth',1.5,'Color',[0.7 0.7 0.7]);
            plot(app.Resp,app.challengeTimeOfExhaleTroughs{app.anoxia},app.challengeExhaleTroughAmplitudes{app.anoxia},'xk','LineWidth',1.5,'Color',[0.7 0.7 0.7])
            
            
            hold(app.Resp,'off')
        end
        
        function functionPipeline(app,pipe)
         %% Extracting autoresuscitation features
          
            if pipe == 1
                % Plotting the data traces
                plotTraces(app,1)

            elseif pipe == 2

                % Plotting the data traces
                plotTraces(app,0)

                % Estimating respiration features
                estimateFeatures(app,0)

                % Finding the point where eupnea is restored
                findRestoration(app)

                % Finding the apneas
                findApneas(app)

                % Finding the gasps
                findGasps(app)

            elseif pipe == 3

                cla(app.Resp)

                % Plotting the data traces
                plotTraces(app,0)

                % Recalculate breathmetrics after editing breaths
                app.challengeBM(app.anoxia) = app.challengeBM(app.anoxia).manualAdjustPostProcess();
                
                estimateFeatures(app,1)

                % Finding the point where eupnea is restored
                findRestoration(app)

                % Finding the apneas
                findApneas(app)

                % Finding the gasps
                findGasps(app)

            elseif pipe == 4
                % Estimating respiration features
                estimateFeatures(app,0)

                % Finding the point where eupnea is restored
                findRestoration(app)

                % Finding the apneas
                findApneas(app)

                % Finding the gasps
                findGasps(app)
            end

          
        end
        
        function spliceAndSlice(app)

            if app.breathsRemoved(app.anoxia)>0

                removeBreathIndex = app.removeBreathPosition{app.anoxia}(:,3);

                app.challengeBM(app.anoxia).inhalePeaks(removeBreathIndex) = [];
                app.challengeBM(app.anoxia).exhaleTroughs(removeBreathIndex) = [];

            end

            if app.manualBreaths(app.anoxia)>0
                newInhaleIndex = app.addInhalePosition{app.anoxia}(:,3)-(app.challengeIndex(app.anoxia)-1);
                
                newExhaleIndex = zeros(size(app.addExhalePosition{app.anoxia}(:,3),1),1);
                for ex = 1:size(app.addExhalePosition{app.anoxia}(:,3),1)
                    if app.addExhalePosition{app.anoxia}(ex,3) == 1
                        newExhaleIndex(ex) = 1;
                    else

                        newExhaleIndex(ex) = app.addExhalePosition{app.anoxia}(ex,3)-(app.challengeIndex(app.anoxia)-1);
                    end
                end

                for breath = 1:app.manualBreaths(app.anoxia)

                    splice = find(newInhaleIndex(breath)<app.challengeBM(app.anoxia).inhalePeaks,1,'first');


                    [common, ~] = intersect((app.addExhalePosition{app.anoxia}(breath,3))-(app.challengeIndex(app.anoxia)-1),...
                        app.challengeBM(app.anoxia).exhaleTroughs);

                    if ~isempty(common)

                        priorPeakInd = app.challengeBM(app.anoxia).inhalePeaks(splice-1)+(app.challengeIndex(app.anoxia)-1);

                        peakToPeak = app.respSegments(app.anoxia,priorPeakInd:...
                            newInhaleIndex(breath)+(app.challengeIndex(app.anoxia)-1));

                        troughThreshold = mean(app.respSegments(app.anoxia,app.challengeIndex(app.anoxia:end)))...
                            - std(app.respSegments(app.anoxia,app.challengeIndex(app.anoxia:end))) / 1.25;
                        [minimas,locs] = findpeaks((-1)*peakToPeak,'MinPeakHeight',(-1)*troughThreshold);


                        if ~isempty(minimas)
                            [mostMin,minInd] = max(minimas);
                            app.challengeBM(app.anoxia).exhaleTroughs(splice-1) = locs(minInd)+app.challengeBM(app.anoxia).inhalePeaks(splice-1);
                            app.challengeBM(app.anoxia).troughExpiratoryFlows(splice-1) = (-1)*mostMin;

                        else
                            app.challengeBM(app.anoxia).exhaleTroughs(splice-1) = 1;
                            app.challengeBM(app.anoxia).troughExpiratoryFlows(splice-1) = 0;
                        end
                    end

                    inBetween = app.addExhalePosition{app.anoxia}(breath,3)-(app.challengeIndex(app.anoxia)-1)<...
                    app.challengeBM(app.anoxia).exhaleTroughs(splice-1);

                    if inBetween
                        
                        priorPeakInd = app.challengeBM(app.anoxia).inhalePeaks(splice-1)+(app.challengeIndex(app.anoxia)-1);

                        peakToPeak = app.respSegments(app.anoxia,priorPeakInd:...
                            newInhaleIndex(breath)+(app.challengeIndex(app.anoxia)-1));

                        troughThreshold = mean(app.respSegments(app.anoxia,app.challengeIndex(app.anoxia:end)))...
                            - std(app.respSegments(app.anoxia,app.challengeIndex(app.anoxia:end))) / 1.25;
                        [minimas,locs] = findpeaks((-1)*peakToPeak,'MinPeakHeight',(-1)*troughThreshold);


                        if ~isempty(minimas)
                            [mostMin,minInd] = max(minimas);
                            app.challengeBM(app.anoxia).exhaleTroughs(splice-1) = locs(minInd)+app.challengeBM(app.anoxia).inhalePeaks(splice-1);
                            app.challengeBM(app.anoxia).troughExpiratoryFlows(splice-1) = (-1)*mostMin;

                        else
                            app.challengeBM(app.anoxia).exhaleTroughs(splice-1) = 1;
                            app.challengeBM(app.anoxia).troughExpiratoryFlows(splice-1) = 0;
                        end
                    end

                    app.challengeBM(app.anoxia).inhalePeaks = [app.challengeBM(app.anoxia).inhalePeaks(1:(splice-1))...
                        newInhaleIndex(breath) app.challengeBM(app.anoxia).inhalePeaks(splice:end)];

                    app.challengeBM(app.anoxia).peakInspiratoryFlows = [app.challengeBM(app.anoxia).peakInspiratoryFlows(1:(splice-1))...
                        app.addInhalePosition{app.anoxia}(breath,2) app.challengeBM(app.anoxia).peakInspiratoryFlows(splice:end)];

                    app.challengeBM(app.anoxia).exhaleTroughs = [app.challengeBM(app.anoxia).exhaleTroughs(1:(splice-1))...
                        newExhaleIndex(breath) app.challengeBM(app.anoxia).exhaleTroughs(splice:end)];

                    app.challengeBM(app.anoxia).troughExpiratoryFlows = [app.challengeBM(app.anoxia).troughExpiratoryFlows(1:(splice-1))...
                        app.addExhalePosition{app.anoxia}(breath,2) app.challengeBM(app.anoxia).troughExpiratoryFlows(splice:end)];
                end
            end
        end
    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RemoveBreathButton.Enable = "off";
            app.RecalculateFeaturesButton.Enable = "off";
            app.AddBreathButton.Enable = "off";
            app.HRRestoredButton.Enable = "off";
            app.RegularityButton.Enable = "off";
            app.SecondaryPlotsButton.Enable = "off";
            app.ApneaButton.Enable = "off";
            app.Button.Enable = "off";
            app.GasOffButton.Enable = "off";
            app.GasOnButton.Enable = "off";
            app.EupneicButton.Enable = "off";
            app.JumptoAnoxiaEditField.Enable = "off";
            app.PreviousAnoxiaButton.Enable = "off";
            app.NextAnoxiaButton.Enable = "off";
            app.SaveandExportButton.Enable = "off";
            app.TableButton.Enable = "off";

            app.InhaleVolumesMenu.Enable = 'off';
            app.InhaleVolumesMenu.Checked = 'off';
            app.InhaleAmplitudesMenu.Enable = 'off';
            app.InhaleAmplitudesMenu.Checked = 'off';
            app.PlotMarkersMenu.Enable = 'off';
            app.PlotMarkersMenu.Checked = 'off';

        end

        % Button pushed function: UploadtxtFileButton
        function UploadtxtFileButtonPushed(app, event)
        %% Uploading and processing data        
        if isempty(app.txtUploaded) == 1
         while isempty(app.txtUploaded) == 1

             if isempty(app.CRF)
                 [CRFFile,CRFPath] = uigetfile('*.xlsx','Select the CRF');
                 CRFFilename = [CRFPath CRFFile];
                 app.CRF = readtable(CRFFilename,ReadVariableNames=false);
             end

             % Who is analyzing?
             analyzers = ["Machine";"Bill"; "Jay"; "Julia"; "Luxi"; "Presley"; "Zack"];
             analyzerCode = ["0","1","2","3","4","5","6"];
             app.analyzer = 0;
             while app.analyzer == 0
                 [analyzerIndex,app.analyzer] = listdlg('Name','Analyzer Selection','PromptString',...
                     'Who is analyzing this file?',...
                     'ListString',analyzers,'ListSize',[250 125],'SelectionMode','single');
             end
          waitfor(analyzerIndex)
          app.analyzer = analyzerCode(analyzerIndex);

          % Uploads the .mat file exported from LabChart
            [app.File,Path] = uigetfile('*.txt','Select a data file');
            Filename = [Path app.File];
            processing = uifigure;
            uialert(processing,'Processing selected .txt file...', ...
                'One Moment Please...','Icon','info');
            app.rawData = readtable(Filename,'VariableNamingRule','preserve');
            delete(processing)
            app.txtUploaded = 1;
         end

         app.RemoveBreathButton.Enable = "on";
         app.RecalculateFeaturesButton.Enable = "on";
         app.AddBreathButton.Enable = "on";
         app.HRRestoredButton.Enable = "on";
         app.RegularityButton.Enable = "on";
         app.SecondaryPlotsButton.Enable = "on";
         app.ApneaButton.Enable = "on";
         app.Button.Enable = "on";
         app.GasOffButton.Enable = "on";
         app.GasOnButton.Enable = "on";
         app.EupneicButton.Enable = "on";
         app.JumptoAnoxiaEditField.Enable = "on";
         app.PreviousAnoxiaButton.Enable = "on";
         app.NextAnoxiaButton.Enable = "on";
         app.SaveandExportButton.Enable = "on";
         app.TableButton.Enable = "on";
         app.InhaleVolumesMenu.Enable = 'on';
         app.InhaleAmplitudesMenu.Enable = 'on';
         app.PlotMarkersMenu.Enable = 'on';

          % Displaying the current working file
            app.CurrentFile.Value = erase(app.File,'.txt');

          % All recordings done at 1k Hz
            app.sampleRate = 1000;           

          % Prepping the raw data
            prepData(app)

          % Adds breathmetric functions to path
            currentDirectory = pwd;
            breathDirectory = strcat(currentDirectory,"/breathmetrics-ARS");
            addpath(genpath(breathDirectory))

          % Using breathmetrics to smooth and baseline correct respiratory data
            app.dataType = 'rodentAirflow';
            breathData = ARSBreathmetrics(app.respFlow,app.sampleRate,app.dataType,1);
            breathData.correctRespirationToBaseline('sliding',0,0);
            app.baselineCorrectedRespiration = breathData.baselineCorrectedRespiration;

         %% Initializing variables
          % Variables of plotting and viewing
            app.gasOns = height(app.onMarkers);
            app.NumOfAnoxias.Value = app.gasOns;
            app.gasOffs = app.gasOns;
            app.anoxia = 1;
            app.JumptoAnoxiaEditField.Value = app.anoxia;
            app.analyzed = zeros(app.gasOns,1);
            
          % Variables for saving positions of drawpoints
            app.firstApneaPosition = zeros(app.gasOns,2);
            app.gaspCount = zeros(app.gasOns,1);
            app.firstGaspPosition = zeros(app.gasOns,2);
            app.firstEupneicPosition = zeros(app.gasOns,2);
            app.restore63Position = zeros(app.gasOns,2);
            app.regularityPosition = zeros(app.gasOns,2);
            app.baselineRespPositions = zeros(app.gasOns,4);
            app.heartRate = cell(app.gasOns,1);
            app.RIndex = cell(app.gasOns,1);
            app.RTimes = cell(app.gasOns,1);
            app.RPeaks = cell(app.gasOns,1);
            app.HRTimes = cell(app.gasOns,1);
            app.HRRestoredPosition = zeros(app.gasOns,2);
            app.timeToHRRestored = zeros(app.gasOns,1);
            app.baselineHR = zeros(app.gasOns,1);
            app.breathPositions = cell(app.gasOns,1);
            app.manualBreaths = zeros(app.gasOns,1);
            app.breathsRemoved = zeros(app.gasOns,1);
            app.addInhalePosition = cell(app.gasOns,1);
            app.addExhalePosition = cell(app.gasOns,1);
            app.removeBreathPosition = cell(app.gasOns,1);
            app.abruptPoints = cell(app.gasOns,1);
            app.greaterAmplitudePoints = cell(app.gasOns,1);
            app.bothPoints = cell(app.gasOns,1);
            app.challengeAmplitudes = cell(app.gasOns,1);
            app.challengeTimeOfInhalePeaks = cell(app.gasOns,1);
            app.challengeInhaleVols = cell(app.gasOns,1);

          % Variables for measurements
            app.challengeIndex = (90001)*ones(app.gasOns,1);
            app.timeToApnea = zeros(app.gasOns,1);
            app.timeToRestore63 = zeros(app.gasOns,1);
            app.timeToRegularity = zeros(app.gasOns,1);
            app.timeToFirstEupneic = zeros(app.gasOns,1);
            app.gaspLatency = zeros(app.gasOns,1);
            app.baselineRespFeatures = zeros(app.gasOns,24);
            app.longestApnea = zeros(app.gasOns,1);
            app.numOfApneas = zeros(app.gasOns,1);
            app.transitionBreaths = zeros(app.gasOns,3);
            app.baselineMinVent = cell(app.gasOns,1);
            app.challengeMinVent = cell(app.gasOns,1);
            app.baselineTimes = cell(app.gasOns,1);
            app.challengeTimes = cell(app.gasOns,1);
            app.challengeYs = cell(app.gasOns,1);
            app.rawDataTable = cell(app.gasOns+1,1);
            app.timeSpentMoving = zeros(app.gasOns,4);
            app.automated = 0;

            varNames = ["_","Anoxia 1","Anoxia 2","Anoxia 3","Anoxia 4",...
                "Anoxia 5","Anoxia 6","Anoxia 7","Anoxia 8","Anoxia 9","Anoxia 10"];
            varTypes = ["string","double","double","double","double","double",...
                "double","double","double","double","double"];
            app.baselineFeatTable = table('Size',[5,(app.gasOns+1)], ...
                'VariableTypes',varTypes(1:(app.gasOns+1)),'VariableNames',varNames(1:(app.gasOns+1)));
            app.baselineFeatTable(:,1) = {"RR (breath/min)";"IBI (s)";"Amp";"Inhale Volume";"HR (beat/min)";};
            app.isGasp = cell(app.gasOns,1);
            blank = [];
            for anoxias = 1:app.gasOns
                blank = [blank; ARSBreathmetrics(0,app.sampleRate,app.dataType,0)];
            end
            app.baselineBM = blank;
            app.challengeBM = blank;

         %% Extracting autoresuscitation features
           % Isolating the anoxia events  
            anoxiaChop(app)
            functionPipeline(app,2)
            
         else
             answer = questdlg('Would you like to upload a different file?', ...
             	'Uploading .txt File', ...
            	'Yes, please','No, thank you','No, thank you');

             switch answer
                 case 'Yes, please'
                     cla(app.Resp) % Clearing Resp axis
                     cla(app.ECG) % Clearing ECG axis
                     cla(app.Piezo) % Clearing Piezo axis
                     app.txtUploaded = [];
                     UploadtxtFileButtonPushed(app, event)
                 case 'No, thank you'
             end
        end


        end

        % Button pushed function: NextAnoxiaButton
        function NextAnoxiaButtonPushed(app, event)

            app.PlotMarkersMenu.Checked = 'off';
            app.InhaleAmplitudesMenu.Checked = 'off';
            app.InhaleAmplitudesMenu.Checked = 'off';

          % This jumps to the next gas on markers
            if app.anoxia < app.gasOns
                app.anoxia = app.anoxia+1;
            elseif app.anoxia == app.gasOns
            end

          % Updating the number in JumptoAnoxiaEditField
            app.JumptoAnoxiaEditField.Value = app.anoxia;

          % If the anoxia has NOT been analyzed, plot and analzye
          if app.analyzed(app.anoxia) == 0
             functionPipeline(app,2)
              
          % If the anoxia has been analyzed, just plot traces and points
          elseif app.analyzed(app.anoxia) == 1
             % Plotting the data traces for the anoxia segment
              functionPipeline(app,1)
          end 

        end

        % Button pushed function: PreviousAnoxiaButton
        function PreviousAnoxiaButtonPushed(app, event)
            
            app.PlotMarkersMenu.Checked = 'off';
            app.InhaleAmplitudesMenu.Checked = 'off';
            app.InhaleVolumesMenu.Checked = 'off';

            % This jumps to the previous gas on markers
            if app.anoxia > 1 && app.anoxia <= app.gasOns
                app.anoxia = app.anoxia-1;
            elseif app.anoxia == 1
            end

          % Updating the number in JumptoAnoxiaEditField
            app.JumptoAnoxiaEditField.Value = app.anoxia;

          % Plotting the data traces for the anoxia segment
            functionPipeline(app,1)

        end

        % Value changed function: JumptoAnoxiaEditField
        function JumptoAnoxiaEditFieldValueChanged(app, event)
          % Jumps to an anoxia
            value = app.JumptoAnoxiaEditField.Value;
            app.PlotMarkersMenu.Checked = 'off';
            app.InhaleAmplitudesMenu.Checked = 'off';
            app.InhaleVolumesMenu.Checked = 'off';

            if value >= 1 && value <= app.gasOns
                app.anoxia = value;
            else
                error = uifigure;
                uialert(error,"Invalid entry, try again.",'Invalid Entry','Icon','error');
                uiwait(error);
            end

            % If the anoxia has NOT been analyzed, plot and analzye
            if app.analyzed(app.anoxia) == 0
                functionPipeline(app,2)

                % If the anoxia has been analyzed, just plot traces and points
            elseif app.analyzed(app.anoxia) == 1
                % Plotting the data traces for the anoxia segment
                functionPipeline(app,1)
            end


        end

        % Key press function: AutoresuscitationAnalysisGUIUIFigure
        function KeyPress(app, event)
            key = event.Key;
            switch key
                case 'n'
                    NextAnoxiaButtonPushed(app,event)
                case 'p'
                    PreviousAnoxiaButtonPushed(app,event)
                case 'a'
                    ShiftApneaButtonPushed(app, event)
            end
        end

        % Button pushed function: GasOnButton
        function GasOnButtonPushed(app, event)
            xlim(app.Resp,app.plotWindow)
            xlim(app.ECG,app.plotWindow)
        end

        % Button pushed function: GasOffButton
        function GasOffButtonPushed(app, event)
            offPlotWindow = [app.segmentMarkersIndices(app.anoxia,2)-5,...
                app.segmentMarkersIndices(app.anoxia,2)+5];
            xlim(app.Resp,offPlotWindow)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            eupneaPlotWindow = [app.restore63Position(app.anoxia,1)-5,...
                app.restore63Position(app.anoxia,1)+5];
            xlim(app.Resp,eupneaPlotWindow)
        end

        % Button pushed function: ApneaButton
        function ApneaButtonPushed(app, event)
            firstApneaPlotWindow = [app.firstApneaPosition(app.anoxia,1)-5,...
                app.firstApneaPosition(app.anoxia,1)+5];
            xlim(app.Resp,firstApneaPlotWindow)
        end

        % Button pushed function: SaveandExportButton
        function SaveandExportButtonPushed(app, event)
            CRFDetails = table2cell(app.CRF(contains(string(app.CRF{:,3}), ...
                caseInsensitivePattern(erase(app.File,[".txt"," "]))),:));
            
            typesOfExperiment = ["Wild Type Bl6","Transgenic","Drug Injection"];

            genotypeCRF = cell2mat(CRFDetails(13));
            injectionCRF = cell2mat(CRFDetails(19));

            % if (genotypeCRF==0) && (injectionCRF==0)
            %     experimentType = typesOfExperiment(1);
            % 
            % elseif (genotypeCRF~=0) && (injectionCRF==0)
            %     experimentType = typesOfExperiment(2);
            % 
            % elseif (genotypeCRF==0) && (injectionCRF~=0)
            %     experimentType = typesOfExperiment(3);
            % else
            %     experimentType = questdlg({"Choose the Experiment Type:",app.demogCom},"Experiment Type",...
            %     "Wild Type Bl6","Transgenic","Drug Injection","Wild Type Bl6");
            % end

            experimentType = questdlg({"Choose the Experiment Type:",app.demogCom},"Experiment Type",...
                "Wild Type Bl6","Transgenic","Drug Injection","Wild Type Bl6");

            if experimentType == "Wild Type Bl6"
                % Selcting the hypoxia precondition type

                % details = cell2mat([CRFDetails(10),CRFDetails(15),CRFDetails(16),CRFDetails(18),...
                %     CRFDetails(14),CRFDetails(79),CRFDetails(80)]);

                details = NaN;

                if isnan(details)

                    validExposure = ["None", "IH", "RA","none","ih","ra","IH5","RA5","ih5","ra5"];
                    exposureCodes = [0,1,2,0,1,2,3,4,3,4];
                    validGender = ["M","F","f","m"];
                    genderCode = [2,1,2,1];
                    validAnoxia = ["Until apnea", "45s", "90s"];
                    anoxiaCodes = [0,1,2];
                    validSetup = ["1-134", "Presley", "Julia", "Unknown"];
                    setupCodes = [0,1,2,3];
                    validExperimenter = ["Bill", "Jay", "Julia", "Luxi", "Presley", "Zack","Bill/Luxi"];
                    experimenterCodes = [1,2,3,4,5,6,7];

                    prompts = {[app.demogCom;"Enter the hypoxia exposure type: (None, IH, or RA)"],"Enter the pup's gender: (M or F)",...
                        "Enter the pup's weight: (in grams)","Enter the anoxia type: (Until apnea, 45s, 90s)",...
                        "Enter the pup's age: (days of age)", "Enter the Experimental Setup: (1-134, Presley, Julia, Unknown)",...
                        ["Enter the Experimenter:" ;"(Bill, Jay, Julia, Luxi, Presley, Zack, Bill/Luxi)"]};
                    dlgtitle = 'Analysis Details';
                    fieldSize = [1 60;1 60;1 60;1 60;1 60; 1 60; 1 60];
                    inputDetails = inputdlg(prompts,dlgtitle,fieldSize);

                    validDetails = false;
                    while ~validDetails
                        validDetails = false; 
                        
                        exposureVaild = any(strcmp(inputDetails(1),validExposure));
                        genderVaild = any(strcmp(inputDetails(2),validGender));
                        weightValid = str2double(inputDetails{3})>0;
                        anoxiaVaild = any(strcmp(inputDetails(4),validAnoxia));
                        ageValid = str2double(inputDetails{5})>0;
                        setUpVaild = any(strcmp(inputDetails(6),validSetup));
                        experimenterValid = any(strcmp(inputDetails(7),validExperimenter));
                        validCheck = [exposureVaild,genderVaild,weightValid,anoxiaVaild,ageValid,setUpVaild,experimenterValid];
                        
                        if all(validCheck) 
                            app.hypoxiaPC = exposureCodes(strcmp(inputDetails(1),validExposure));
                            app.gender = genderCode(strcmp(inputDetails(2),validGender));
                            app.weight = str2double(inputDetails{3});
                            app.anoxiaType = anoxiaCodes(strcmp(inputDetails(4),validAnoxia));
                            app.age = str2double(inputDetails{5});
                            app.experimentSetup = setupCodes(strcmp(inputDetails(6),validSetup));
                            app.experimenter = experimenterCodes(strcmp(inputDetails(7),validExperimenter));
                            validDetails = true;
                        else
                            prompts = {[app.demogCom;"Enter the hypoxia exposure type: (No exposure, IH, or RA)"],"Enter the pup's gender: (M or F)",...
                                "Enter the pup's weight: (in grams)","Enter the anoxia type: (Until apnea, 45s, 90s)",...
                                "Enter the pup's age: (days of age)", "Enter the Experimental Setup: (1-134, Presley, Julia, Unknown)",...
                                ["Enter the Experimenter:" ;"(Bill, Jay, Julia, Luxi, Presley, Zack, Bill/Luxi)"]};
                            dlgtitle = 'Analysis Details';
                            fieldSize = [1 60;1 60;1 60;1 60;1 60; 1 60; 1 60];
                            prefills = {'','','','','','',''};
                            prefills(validCheck) = inputDetails(validCheck);
                            inputDetails = inputdlg(prompts,dlgtitle,fieldSize,prefills);
                        end
                    end
                else
                    % If all the details can be found from the CRF, they
                    % are assigned 
                    app.hypoxiaPC = details(1);
                    app.gender = details(2);
                    app.weight = details(3);
                    app.anoxiaType = details(4);
                    app.age = details(5);
                    app.experimentSetup = details(6);
                    CRFExptr = [0,1,2,3,4,5]; 
                    Exptr = [4,1,5,3,6,7]; 
                    app.experimenter = Exptr(details(7)==CRFExptr);
                end

                % Time Spent Moving

                for p =1:app.gasOns

                    %activityThreshold = mean(app.percentSegments(p,:))+(0.5*std(app.percentSegments(p,:)));
                    activityThreshold = 1;
                    moving = app.percentSegments(p,:)>activityThreshold;

                    app.timeSpentMoving(p,1) = sum(moving(1:90000))./1000;

                    gasOffIndex = find(app.timeSegments(p,:)==app.offMarkers(p,3));
                    app.timeSpentMoving(p,2) = sum(moving(90001:gasOffIndex))./1000;

                    if app.restore63Position(p,1)>app.regularityPosition(p,1)
                        recoveryIndex = find(app.timeSegments(p,:)==app.restore63Position(p,1));
                    else
                        recoveryIndex = find(app.timeSegments(p,:)==app.regularityPosition(p,1));
                    end
                    app.timeSpentMoving(p,3) = sum(moving(gasOffIndex:recoveryIndex))./1000;

                    app.timeSpentMoving(p,4) = sum(moving(recoveryIndex:end))./1000;

                    app.rawDataTable{p}.Weight = app.rawDataTable{p}.Weight*app.weight;
                    app.rawDataTable{p}.Gender = app.rawDataTable{p}.Gender*app.gender;
                    app.rawDataTable{p}.Age = app.rawDataTable{p}.Age*app.age;
                    app.rawDataTable{p}.AnoxiaType = app.rawDataTable{p}.AnoxiaType*app.anoxiaType;
                    app.rawDataTable{p}.ExperimentSetup = app.rawDataTable{p}.ExperimentSetup*app.experimentSetup;
                    app.rawDataTable{p}.Experimenter = app.rawDataTable{p}.Experimenter*app.experimenter;
                    app.rawDataTable{p}.hypoxiaPreCond = app.rawDataTable{p}.hypoxiaPreCond*app.hypoxiaPC;
                end

                ID = erase(app.File,'.txt');
                animalID = char(repmat({ID},app.gasOns,1));
                hypoxiaPreCond = app.hypoxiaPC*ones(app.gasOns,1);
                Reader = str2double(app.analyzer)*ones(app.gasOns,1);
                ExperimentSetup = app.experimentSetup*ones(app.gasOns,1);
                Experimenter = app.experimenter*ones(app.gasOns,1);
                AnoxiaType = app.anoxiaType*ones(app.gasOns,1);
                Gender = app.gender*ones(app.gasOns,1);
                Weight = app.weight*ones(app.gasOns,1);
                Age = app.age*ones(app.gasOns,1);
                Trial = (1:1:app.gasOns)';
                Mean_Pre_HR = app.baselineHR;
                Mean_Pre_RR = app.baselineRespFeatures(:,11)*60;
                Time_to_1st_Apnea = app.timeToApnea;
                Gas_Duration = app.anoxiaDurations;
                Gasp_Latency = app.gaspLatency;
                Longest_Apnea = app.longestApnea;
                Time_to_HR_63 = app.timeToHRRestored;
                Time_to_1st_Eupneic = app.timeToFirstEupneic;
                Time_to_RR_63 = app.timeToRestore63;
                Time_to_R_Reg = app.timeToRegularity;
                Time_Spent_Moving_B = app.timeSpentMoving(:,1);
                Time_Spent_Moving_Anoxic = app.timeSpentMoving(:,2);
                Time_Spent_Moving_Recovery = app.timeSpentMoving(:,3);
                Time_Spent_Moving_Post = app.timeSpentMoving(:,4);

                if app.tempIncluded

                    Pre_Temp = app.trialTemps(:,1);
                    Post_Temp = app.trialTemps(:,2);

                    app.summaryTable = table(Reader,ExperimentSetup,Experimenter,animalID,Gender,Weight,Age,hypoxiaPreCond,...
                        AnoxiaType,Trial,Mean_Pre_HR,Mean_Pre_RR,Pre_Temp,...
                        Time_to_1st_Apnea,Gas_Duration,Gasp_Latency,...
                        Longest_Apnea,Post_Temp,Time_to_HR_63,Time_to_1st_Eupneic,Time_to_RR_63,Time_to_R_Reg,...
                        Time_Spent_Moving_B,Time_Spent_Moving_Anoxic,Time_Spent_Moving_Recovery,Time_Spent_Moving_Post);

                else
                    app.summaryTable = table(Reader,ExperimentSetup,Experimenter,animalID,Gender,Weight,Age,hypoxiaPreCond,...
                        AnoxiaType,Trial,Mean_Pre_HR,Mean_Pre_RR,...
                        Time_to_1st_Apnea,Gas_Duration,Gasp_Latency,...
                        Longest_Apnea,Time_to_HR_63,Time_to_1st_Eupneic,Time_to_RR_63,Time_to_R_Reg,...
                        Time_Spent_Moving_B,Time_Spent_Moving_Anoxic,Time_Spent_Moving_Recovery,Time_Spent_Moving_Post);
                end

            elseif experimentType == "Transgenic" 

                genotype = genotypeCRF;

                % details = cell2mat([CRFDetails(10),CRFDetails(15),CRFDetails(16),CRFDetails(18),...
                %     CRFDetails(14),CRFDetails(79),CRFDetails(80)]);

                details = NaN;

                if isnan(details)

                    validExposure = ["None", "IH", "RA"];
                    exposureCodes = [0,1,2];
                    validGender = ["M","F","f","m"];
                    genderCode = [2,1,2,1];
                    validAnoxia = ["Until apnea", "45s", "90s"];
                    anoxiaCodes = [0,1,2];
                    validSetup = ["1-134", "Presley", "Julia", "Unknown"];
                    setupCodes = [0,1,2,3];
                    validExperimenter = ["Bill", "Jay", "Julia", "Luxi", "Presley", "Zack","Bill/Luxi"];
                    experimenterCodes = [1,2,3,4,5,6,7];

                    prompts = {[app.demogCom;"Enter the hypoxia exposure type: (None, IH, or RA)"],"Enter the pup's gender: (M or F)",...
                        "Enter the pup's weight: (in grams)","Enter the anoxia type: (Until apnea, 45s, 90s)",...
                        "Enter the pup's age: (days of age)", "Enter the Experimental Setup: (1-134, Presley, Julia, Unknown)",...
                        ["Enter the Experimenter:" ;"(Bill, Jay, Julia, Luxi, Presley, Zack, Bill/Luxi)"]};
                    dlgtitle = 'Analysis Details';
                    fieldSize = [1 60;1 60;1 60;1 60;1 60; 1 60; 1 60];
                    inputDetails = inputdlg(prompts,dlgtitle,fieldSize);

                    validDetails = false;
                    while ~validDetails
                        validDetails = false;

                        exposureVaild = any(strcmp(inputDetails(1),validExposure));
                        genderVaild = any(strcmp(inputDetails(2),validGender));
                        weightValid = str2double(inputDetails{3})>0;
                        anoxiaVaild = any(strcmp(inputDetails(4),validAnoxia));
                        ageValid = str2double(inputDetails{5})>0;
                        setUpVaild = any(strcmp(inputDetails(6),validSetup));
                        experimenterValid = any(strcmp(inputDetails(7),validExperimenter));
                        validCheck = [exposureVaild,genderVaild,weightValid,anoxiaVaild,ageValid,setUpVaild,experimenterValid];

                        if all(validCheck)
                            app.hypoxiaPC = exposureCodes(strcmp(inputDetails(1),validExposure));
                            app.gender = genderCode(strcmp(inputDetails(2),validGender));
                            app.weight = str2double(inputDetails{3});
                            app.anoxiaType = anoxiaCodes(strcmp(inputDetails(4),validAnoxia));
                            app.age = str2double(inputDetails{5});
                            app.experimentSetup = setupCodes(strcmp(inputDetails(6),validSetup));
                            app.experimenter = experimenterCodes(strcmp(inputDetails(7),validExperimenter));
                            validDetails = true;
                        else
                            prompts = {[app.demogCom;"Enter the hypoxia exposure type: (No exposure, IH, or RA)"],"Enter the pup's gender: (M or F)",...
                                "Enter the pup's weight: (in grams)","Enter the anoxia type: (Until apnea, 45s, 90s)",...
                                "Enter the pup's age: (days of age)", "Enter the Experimental Setup: (1-134, Presley, Julia, Unknown)",...
                                ["Enter the Experimenter:" ;"(Bill, Jay, Julia, Luxi, Presley, Zack, Bill/Luxi)"]};
                            dlgtitle = 'Analysis Details';
                            fieldSize = [1 60;1 60;1 60;1 60;1 60; 1 60; 1 60];
                            prefills = {'','','','','','',''};
                            prefills(validCheck) = inputDetails(validCheck);
                            inputDetails = inputdlg(prompts,dlgtitle,fieldSize,prefills);
                        end
                    end
                else
                    % If all the details can be found from the CRF, they
                    % are assigned
                    app.hypoxiaPC = details(1);
                    app.gender = details(2);
                    app.weight = details(3);
                    app.anoxiaType = details(4);
                    app.age = details(5);
                    app.experimentSetup = details(6);
                    CRFExptr = [0,1,2,3,4,5];
                    Exptr = [4,1,5,3,6,7];
                    app.experimenter = Exptr(details(7)==CRFExptr);
                end

                for p =1:app.gasOns

                    activityThreshold = mean(app.percentSegments(p,:))+(0.5*std(app.percentSegments(p,:)));
                    moving = app.percentSegments(p,:)>activityThreshold;

                    app.timeSpentMoving(p,1) = sum(moving(1:90000))./1000;

                    gasOffIndex = round(90001+(app.anoxiaDurations(p)*1000));
                    app.timeSpentMoving(p,2) = sum(moving(90001:gasOffIndex))./1000;

                    if app.restore63Position(app.anoxia,1)>app.regularityPosition(app.anoxia,1)
                        recoveryIndex = round(find(app.timeSegments(app.anoxia,:)==app.restore63Position(app.anoxia,1)));
                    else
                        recoveryIndex = round(find(app.timeSegments(app.anoxia,:)==app.regularityPosition(app.anoxia,1)));
                    end
                    app.timeSpentMoving(p,3) = sum(moving(gasOffIndex:recoveryIndex))./1000;

                    app.timeSpentMoving(p,4) = sum(moving(recoveryIndex:end))./1000;
                end

                ID = erase(app.File,'.txt');
                animalID = char(repmat({ID},app.gasOns,1));
                Genotype = genotype*ones(app.gasOns,1);
                hypoxiaPreCond = app.hypoxiaPC*ones(app.gasOns,1);
                Reader = str2double(app.analyzer)*ones(app.gasOns,1);
                ExperimentSetup = app.experimentSetup*ones(app.gasOns,1);
                Experimenter = app.experimenter*ones(app.gasOns,1);
                AnoxiaType = app.anoxiaType*ones(app.gasOns,1);
                Gender = app.gender*ones(app.gasOns,1);
                Weight = app.weight*ones(app.gasOns,1);
                Age = app.age*ones(app.gasOns,1);
                Trial = (1:1:app.gasOns)';
                Mean_Pre_HR = app.baselineHR;
                Mean_Pre_RR = app.baselineRespFeatures(:,11)*60;
                Time_to_1st_Apnea = app.timeToApnea;
                Gas_Duration = app.anoxiaDurations;
                Gasp_Latency = app.gaspLatency;
                Longest_Apnea = app.longestApnea;
                Time_to_HR_63 = app.timeToHRRestored;
                Time_to_1st_Eupneic = app.timeToFirstEupneic;
                Time_to_RR_63 = app.timeToRestore63;
                Time_to_R_Reg = app.timeToRegularity;
                Time_Spent_Moving_B = app.timeSpentMoving(:,1);
                Time_Spent_Moving_Anoxic = app.timeSpentMoving(:,2);
                Time_Spent_Moving_Recovery = app.timeSpentMoving(:,3);
                Time_Spent_Moving_Post = app.timeSpentMoving(:,4);

                if app.tempIncluded

                    Pre_Temp = app.trialTemps(:,1);
                    Post_Temp = app.trialTemps(:,2);

                    app.summaryTable = table(Reader,ExperimentSetup,Experimenter,animalID,Gender,Weight,Age,Genotype,hypoxiaPreCond,...
                        AnoxiaType,Trial,Mean_Pre_HR,Mean_Pre_RR,Pre_Temp,...
                        Time_to_1st_Apnea,Gas_Duration,Gasp_Latency,...
                        Longest_Apnea,Post_Temp,Time_to_HR_63,Time_to_1st_Eupneic,Time_to_RR_63,Time_to_R_Reg,...
                        Time_Spent_Moving_B,Time_Spent_Moving_Anoxic,Time_Spent_Moving_Recovery,Time_Spent_Moving_Post);

                else
                    app.summaryTable = table(Reader,ExperimentSetup,Experimenter,animalID,Gender,Weight,Age,Genotype,hypoxiaPreCond,...
                        AnoxiaType,Trial,Mean_Pre_HR,Mean_Pre_RR,...
                        Time_to_1st_Apnea,Gas_Duration,Gasp_Latency,...
                        Longest_Apnea,Time_to_HR_63,Time_to_1st_Eupneic,Time_to_RR_63,Time_to_R_Reg,...
                        Time_Spent_Moving_B,Time_Spent_Moving_Anoxic,Time_Spent_Moving_Recovery,Time_Spent_Moving_Post);
                end

                for s = 1:app.gasOns
                    Genes = genotype*ones(height(app.rawDataTable{s}),1);

                    tempTable = app.rawDataTable{s};
                    insertTable = addvars(tempTable,Genes,'After','animalID');

                    insertTable.Weight = insertTable.Weight*app.weight;
                    insertTable.Gender = insertTable.Gender*app.gender;
                    insertTable.Age = insertTable.Age*app.age;
                    insertTable.AnoxiaType = insertTable.AnoxiaType*app.anoxiaType;
                    insertTable.ExperimentSetup = insertTable.ExperimentSetup*app.experimentSetup;

                    app.rawDataTable{s} = insertTable;
                    app.rawDataTable{s}.hypoxiaPreCond = app.rawDataTable{s}.hypoxiaPreCond*app.hypoxiaPC;
                end

            elseif experimentType == "Drug Injection"

                %drugDetails = [injectionCRF,cell2mat(CRFDetails(20)),cell2mat(CRFDetails(22))];
                drugDetails = NaN;
                validDrug = 0;

                if isnan(drugDetails)
                    while validDrug == 0
                        drugPromts = {[app.demogCom;"Enter the drug used: (LPS, PolyIC, or Saline)"], "Enter the dose: (uL)","Enter wait time: (minutes)"};
                        drugTitle = 'Drug Details';
                        Sizes = [1 60;1 60;1 60];
                        drugDetails = inputdlg(drugPromts,drugTitle,Sizes);

                        if contains(drugDetails{1},"lps","IgnoreCase",true) || contains(drugDetails{1},"polyic","IgnoreCase",true) ...
                                || contains(drugDetails{1},"saline","IgnoreCase",true)
                            validDrug = 1;
                        end
                    end
                    if contains(drugDetails{1},"lps","IgnoreCase",true)
                        drug = 1;
                    elseif contains(drugDetails{1},"polyic","IgnoreCase",true)
                        drug = 2;
                    elseif contains(drugDetails{1},"saline","IgnoreCase",true)
                        drug = 0;
                    end
                    dose = str2double(cell2mat(drugDetails(2)));
                    waittime = str2double(cell2mat(drugDetails(3)));
                else
                    drug = drugDetails(1);
                    dose = drugDetails(2);
                    waittime = drugDetails(3);
                end


                % details = cell2mat([CRFDetails(10),CRFDetails(15),CRFDetails(16),CRFDetails(18),...
                %     CRFDetails(14),CRFDetails(79),CRFDetails(80)]);
                details = NaN;

                if isnan(details)

                    validExposure = ["None", "IH", "RA","none","ih","ra"];
                    exposureCodes = [0,1,2,0,1,2];
                    validGender = ["M","F","f","m"];
                    genderCode = [2,1,2,1];
                    validAnoxia = ["Until apnea", "45s", "90s","until apnea"];
                    anoxiaCodes = [0,1,2,0];
                    validSetup = ["1-134", "Presley", "Julia", "Unknown"];
                    setupCodes = [0,1,2,3];
                    validExperimenter = ["Bill", "Jay", "Julia", "Luxi", "Presley", "Zack","Bill/Luxi"];
                    experimenterCodes = [1,2,3,4,5,6,7];

                    prompts = {[app.demogCom;"Enter the hypoxia exposure type: (None, IH, or RA)"],"Enter the pup's gender: (M or F)",...
                        "Enter the pup's weight: (in grams)","Enter the anoxia type: (Until apnea, 45s, 90s)",...
                        "Enter the pup's age: (days of age)", "Enter the Experimental Setup: (1-134, Presley, Julia, Unknown)",...
                        ["Enter the Experimenter:" ;"(Bill, Jay, Julia, Luxi, Presley, Zack, Bill/Luxi)"]};
                    dlgtitle = 'Analysis Details';
                    fieldSize = [1 60;1 60;1 60;1 60;1 60; 1 60; 1 60];
                    inputDetails = inputdlg(prompts,dlgtitle,fieldSize);

                    validDetails = false;
                    while ~validDetails
                        validDetails = false;

                        exposureVaild = any(strcmp(inputDetails(1),validExposure));
                        genderVaild = any(strcmp(inputDetails(2),validGender));
                        weightValid = str2double(inputDetails{3})>0;
                        anoxiaVaild = any(strcmp(inputDetails(4),validAnoxia));
                        ageValid = str2double(inputDetails{5})>0;
                        setUpVaild = any(strcmp(inputDetails(6),validSetup));
                        experimenterValid = any(strcmp(inputDetails(7),validExperimenter));
                        validCheck = [exposureVaild,genderVaild,weightValid,anoxiaVaild,ageValid,setUpVaild,experimenterValid];

                        if all(validCheck)
                            app.hypoxiaPC = exposureCodes(strcmp(inputDetails(1),validExposure));
                            app.gender = genderCode(strcmp(inputDetails(2),validGender));
                            app.weight = str2double(inputDetails{3});
                            app.anoxiaType = anoxiaCodes(strcmp(inputDetails(4),validAnoxia));
                            app.age = str2double(inputDetails{5});
                            app.experimentSetup = setupCodes(strcmp(inputDetails(6),validSetup));
                            app.experimenter = experimenterCodes(strcmp(inputDetails(7),validExperimenter));
                            validDetails = true;
                        else
                            prompts = {[app.demogCom;"Enter the hypoxia exposure type: (No exposure, IH, or RA)"],"Enter the pup's gender: (M or F)",...
                                "Enter the pup's weight: (in grams)","Enter the anoxia type: (Until apnea, 45s, 90s)",...
                                "Enter the pup's age: (days of age)", "Enter the Experimental Setup: (1-134, Presley, Julia, Unknown)",...
                                ["Enter the Experimenter:" ;"(Bill, Jay, Julia, Luxi, Presley, Zack, Bill/Luxi)"]};
                            dlgtitle = 'Analysis Details';
                            fieldSize = [1 60;1 60;1 60;1 60;1 60; 1 60; 1 60];
                            prefills = {'','','','','','',''};
                            prefills(validCheck) = inputDetails(validCheck);
                            inputDetails = inputdlg(prompts,dlgtitle,fieldSize,prefills);
                        end
                    end
                else
                    % If all the details can be found from the CRF, they
                    % are assigned
                    app.hypoxiaPC = details(1);
                    app.gender = details(2);
                    app.weight = details(3);
                    app.anoxiaType = details(4);
                    app.age = details(5);
                    app.experimentSetup = details(6);
                    CRFExptr = [0,1,2,3,4,5];
                    Exptr = [4,1,5,3,6,7];
                    app.experimenter = Exptr(details(7)==CRFExptr);
                end

                for p =1:app.gasOns

                    activityThreshold = mean(app.percentSegments(p,:))+(0.5*std(app.percentSegments(p,:)));
                    moving = app.percentSegments(p,:)>activityThreshold;

                    app.timeSpentMoving(p,1) = sum(moving(1:90000))./1000;

                    gasOffIndex = round(90001+(app.anoxiaDurations(p)*1000));
                    app.timeSpentMoving(p,2) = sum(moving(90001:gasOffIndex))./1000;

                    if app.restore63Position(app.anoxia,1)>app.regularityPosition(app.anoxia,1)
                        recoveryIndex = round(find(app.timeSegments(app.anoxia,:)==app.restore63Position(app.anoxia,1)));
                    else
                        recoveryIndex = round(find(app.timeSegments(app.anoxia,:)==app.regularityPosition(app.anoxia,1)));
                    end
                    app.timeSpentMoving(p,3) = sum(moving(gasOffIndex:recoveryIndex))./1000;

                    app.timeSpentMoving(p,4) = sum(moving(recoveryIndex:end))./1000;
                end

                ID = erase(char(app.File),{'.txt',' '});
                animalID = char(repmat({ID},1,app.gasOns));
                hypoxiaPreCond = app.hypoxiaPC*ones(app.gasOns,1);
                Reader = str2double(app.analyzer)*ones(app.gasOns,1);
                ExperimentSetup = app.experimentSetup*ones(app.gasOns,1);
                Experimenter = app.experimenter*ones(app.gasOns,1);
                AnoxiaType = app.anoxiaType*ones(app.gasOns,1);
                Gender = app.gender*ones(app.gasOns,1);
                Weight = app.weight*ones(app.gasOns,1);
                Age = app.age*ones(app.gasOns,1);
                Trial = (1:1:app.gasOns)';
                Mean_Pre_HR = app.baselineHR;
                Mean_Pre_RR = app.baselineRespFeatures(:,11)*60;
                Time_to_1st_Apnea = app.timeToApnea;
                Gas_Duration = app.anoxiaDurations;
                Gasp_Latency = app.gaspLatency;
                Longest_Apnea = app.longestApnea;
                Time_to_HR_63 = app.timeToHRRestored;
                Time_to_1st_Eupneic = app.timeToFirstEupneic;
                Time_to_RR_63 = app.timeToRestore63;
                Time_to_R_Reg = app.timeToRegularity;
                Time_Spent_Moving_B = app.timeSpentMoving(:,1);
                Time_Spent_Moving_Anoxic = app.timeSpentMoving(:,2);
                Time_Spent_Moving_Recovery = app.timeSpentMoving(:,3);
                Time_Spent_Moving_Post = app.timeSpentMoving(:,4);
                DrugType = drug*ones(app.gasOns,1);
                DrugDose = dose*ones(app.gasOns,1);
                WaitTime = waittime*ones(app.gasOns,1);

                if app.tempIncluded

                    Pre_Temp = app.trialTemps(:,1);
                    Post_Temp = app.trialTemps(:,2);

                    app.summaryTable = table(Reader,ExperimentSetup,Experimenter,animalID,DrugType,DrugDose,WaitTime,Gender,Weight,Age,...
                        hypoxiaPreCond,AnoxiaType,Trial,Mean_Pre_HR,Mean_Pre_RR,Pre_Temp,...
                        Time_to_1st_Apnea,Gas_Duration,Gasp_Latency,...
                        Longest_Apnea,Post_Temp,Time_to_HR_63,Time_to_1st_Eupneic,Time_to_RR_63,Time_to_R_Reg,...
                        Time_Spent_Moving_B,Time_Spent_Moving_Anoxic,Time_Spent_Moving_Recovery,Time_Spent_Moving_Post);

                else
                    app.summaryTable = table(Reader,ExperimentSetup,Experimenter,animalID,DrugType,DrugDose,WaitTime,Gender,...
                    Weight,Age,hypoxiaPreCond,AnoxiaType,Trial,Mean_Pre_HR,Mean_Pre_RR,...
                    Time_to_1st_Apnea,Gas_Duration,Gasp_Latency,...
                    Longest_Apnea,Time_to_HR_63,Time_to_1st_Eupneic,Time_to_RR_63,Time_to_R_Reg,...
                    Time_Spent_Moving_B,Time_Spent_Moving_Anoxic,Time_Spent_Moving_Recovery,Time_Spent_Moving_Post);
                end

                for s = 1:app.gasOns
                    Drug = drug*ones(height(app.rawDataTable{s}),1);
                    Dose = dose*ones(height(app.rawDataTable{s}),1);
                    Wait = waittime*ones(height(app.rawDataTable{s}),1);

                    tempTable = app.rawDataTable{s};
                    insertTable = addvars(tempTable,Drug,Dose,Wait,'After','animalID');

                    insertTable.Weight = insertTable.Weight*app.weight;
                    insertTable.Gender = insertTable.Gender*app.gender;
                    insertTable.Age = insertTable.Age*app.age;
                    insertTable.AnoxiaType = insertTable.AnoxiaType*app.anoxiaType;
                    insertTable.ExperimentSetup = insertTable.ExperimentSetup*app.experimentSetup;

                    app.rawDataTable{s} = insertTable;
                    app.rawDataTable{s}.hypoxiaPreCond = app.rawDataTable{s}.hypoxiaPreCond*app.hypoxiaPC;
                end

            end

          % Summary Excel File 
            excelFilename = strcat(erase(app.File,'.txt'),"_analyzed_GUI_",string(app.analyzer),".xlsx");

            if app.automated
                excelPath = strcat(app.autoDir,'\',app.analyzedFolderName,'\',excelFilename);

            else
                [uiFilename, uiPath] = uiputfile(excelFilename);
                excelPath = strcat(uiPath,uiFilename);
            end

            writetable(app.summaryTable,excelPath,'Sheet','Trial Summary')

          % Raw Data Excel File
            figure = uifigure('Position',[390 220 600 275]); 
            progress = uiprogressdlg(figure,'Message','Exporting Raw Data to Excel file.');
            for sheet = 1:app.gasOns
                recoveryEnd = find(app.challengeTimes{sheet}==app.restore63Position(sheet,1),1,"first");
                recoveredIndex = length(app.baselineTimes{sheet})+recoveryEnd;
                if ~isempty(recoveryEnd)
                    app.rawDataTable{sheet}.condition(recoveredIndex:end) = 4;
                    app.rawDataTable{sheet}.numWInCond(recoveredIndex:end) =...
                        (1:(length(app.rawDataTable{sheet}.numWInCond(recoveredIndex:end))))';
                end
                
                rawDataSheetName = strcat('Anoxia',32,num2str(sheet));
                writetable(app.rawDataTable{sheet},excelPath,'Sheet',rawDataSheetName)
                progress.Value = sheet/app.gasOns;
            end

            app.rawDataTable{app.gasOns+1} = vertcat(app.rawDataTable{:});
            writetable(app.rawDataTable{app.gasOns+1},excelPath,'Sheet','Data Long')

            close(figure)

            matFilename = strcat(erase(app.File,'.txt'),"_mat_GUI_",string(app.analyzer),".mat");

            if app.automated
                matPath = strcat(app.autoDir,'\',app.analyzedFolderName,'\',matFilename);
            else
                matPath = strcat(uiPath,matFilename);
            end

            TimeSegments = app.timeSegments;
            RespSegments = app.respSegments;
            EcgSegments = app.ecgSegments;
            PiezoSegments = app.piezoSegments;
            OnMarkers = app.onMarkers;
            OffMarkers = app.offMarkers;
            HeartRate = app.heartRate;
            rTimes = app.RTimes;
            hrTimes = app.HRTimes;
            hrRestoredPosition = app.HRRestoredPosition;
            TimeToHRRestored = app.timeToHRRestored;
            FirstApneaPosition = app.firstApneaPosition;
            TimeToApnea = app.timeToApnea;
            LongestApnea = app.longestApnea;
            NumOfApneas = app.NumOfAnoxias;
            FirstGaspPosition = app.firstGaspPosition;
            GaspLatency = app.gaspLatency;
            EupneaPosition = app.restore63Position;
            BreathPositions = app.breathPositions;
            BaselineBM = app.baselineBM;
            ChallengeBM = app.challengeBM;
            BaselineTimes = app.baselineTimes;
            ChallengeTimes = app.challengeTimes;
            ChallengeYs = app.challengeYs;
            SummaryTable = app.summaryTable;
            RawDataTable = app.rawDataTable;

            
            save(matPath,'TimeSegments','RespSegments','EcgSegments', ...
                'PiezoSegments', 'OnMarkers','OffMarkers','HeartRate','rTimes' ...
                ,'hrTimes','hrRestoredPosition','TimeToHRRestored','FirstApneaPosition' ...
                ,'TimeToApnea','LongestApnea','NumOfApneas','FirstGaspPosition' ...
                ,'GaspLatency','EupneaPosition','BreathPositions','BaselineBM' ...
                ,'ChallengeBM','BaselineTimes','ChallengeTimes','ChallengeYs' ...
                ,'SummaryTable','RawDataTable')

        end

        % Button pushed function: SecondaryPlotsButton
        function SecondaryPlotsButtonPushed(app, event)
           % Finding index of the heart rate when gas is turned on
            [~,anoxiaStartHR] = min(abs(app.HRTimes{app.anoxia} - app.onMarkers(app.anoxia,3)));

           % Finding index of breath and heart rate reading when gas if turned off
            anoxicEndMV = find(app.challengeTimes{app.anoxia}>(app.offMarkers(app.anoxia,3)),1,"first");
            anxoicEndHR = find(app.HRTimes{app.anoxia}>(app.offMarkers(app.anoxia,3)),1,"first");

           % Finding index of breath and heart rate reading when eupnea and HR is restored
            recoveryEndMV = find(app.challengeTimes{app.anoxia}==app.restore63Position(app.anoxia,1),1,"first");
            recoveryEndHR = find(app.HRTimes{app.anoxia} == app.HRRestoredPosition(app.anoxia,1),1,"first");

           % Figure for plotting the instantaneous minute ventilation
            secondaryPlots_1 = uifigure("Name",'Secondary Plots'); secondaryPlots_1.WindowState = 'maximized';
            panel_1 = uipanel(secondaryPlots_1); panel_1.AutoResizeChildren = 'off'; panel_1.Position = [0 0 1300 625];
            minVentTitle = strcat('Instantaneous Minute Ventilation',' Anoxia ',32,num2str(app.anoxia));
            HRTitle = strcat('Instantaneous Heart Rate',' Anoxia ',32,num2str(app.anoxia));

           % Plotting each segment of the minute ventilation
            axMV = subplot(2,1,1,'Parent',panel_1); hold(axMV,"on"), grid(axMV,"on"), title(axMV,minVentTitle)
            xlabel(axMV,'Trial Time (seconds)'), ylabel(axMV,'Minute Ventilation')
            axMV.XMinorTick = 'on';
            xlim(axMV,[app.baselineTimes{app.anoxia,1}(1) app.challengeTimes{app.anoxia,1}(end)])

            MVIndex = find(app.rawDataTable{app.anoxia}.condition==1,1,'last');
            MV63 = mean(app.rawDataTable{app.anoxia}.minVent(2:MVIndex))*(1-(1/(exp(1))));

            plot(axMV,app.baselineTimes{app.anoxia,1}(1:(end-1)),app.baselineMinVent{app.anoxia,1})
            plot(axMV,app.challengeTimes{app.anoxia,1}(1:anoxicEndMV),...
                app.challengeMinVent{app.anoxia,1}(1:anoxicEndMV))
            plot(axMV,app.challengeTimes{app.anoxia,1}(anoxicEndMV:recoveryEndMV), ...
                app.challengeMinVent{app.anoxia,1}(anoxicEndMV:recoveryEndMV))
            plot(axMV,app.challengeTimes{app.anoxia,1}(recoveryEndMV:(end-1)), ...
                app.challengeMinVent{app.anoxia,1}(recoveryEndMV:end))
            yline(axMV,MV63,':k')
            legend(axMV,'Baseline Period','Anoxic Period',...
                'Room Air Period','Restored Period','Restored MV Threshold')

            axHR = subplot(2,1,2,'Parent',panel_1); hold(axHR,"on"), grid(axHR,"on"), title(axHR,HRTitle)
            xlabel(axHR,'Trial Time (seconds)'), ylabel(axHR,'Heart Rate (Beats per Minute)')
            axHR.XMinorTick = 'on';
            xlim(axHR,[app.HRTimes{app.anoxia,1}(1) app.HRTimes{app.anoxia,1}(end)])
            plot(axHR,app.HRTimes{app.anoxia,1}(1:anoxiaStartHR), ...
                app.heartRate{app.anoxia,1}(1:anoxiaStartHR),'-o')
            plot(axHR,app.HRTimes{app.anoxia,1}(anoxiaStartHR:anxoicEndHR), ...
                app.heartRate{app.anoxia,1}(anoxiaStartHR:anxoicEndHR),'-o')
            plot(axHR,app.HRTimes{app.anoxia,1}(anxoicEndHR:recoveryEndHR), ...
                app.heartRate{app.anoxia,1}(anxoicEndHR:recoveryEndHR),'-o')
            plot(axHR,app.HRTimes{app.anoxia,1}(recoveryEndHR:end), ...
                app.heartRate{app.anoxia,1}(recoveryEndHR:end),'-o')
            yline(axHR,(app.baselineHR(app.anoxia))*(1-(1/(exp(1)))),':k')
            legend(axHR,'Baseline Period','Anoxic Period',...
                'Room Air Period','Restored Period','Restored HR Threshold')

            linkaxes([axHR,axMV],'x')

        end

        % Button pushed function: HRRestoredButton
        function HRRestoredButtonPushed(app, event)
            HRRestoredPlotWindow = [app.HRRestoredPosition(app.anoxia,1)-5,...
                app.HRRestoredPosition(app.anoxia,1)+5];
            xlim(app.Resp,HRRestoredPlotWindow)
        end

        % Close request function: AutoresuscitationAnalysisGUIUIFigure
        function AutoresuscitationAnalysisGUIUIFigureCloseRequest(app, event)
            closeGUI = uiconfirm(app.AutoresuscitationAnalysisGUIUIFigure,'Do you want to close the app?', ...
                'Close Request','Options',["Yes","No"],"DefaultOption",2,"CancelOption",2);
            if strcmpi(closeGUI,'Yes')
                saveGUI = uiconfirm(app.AutoresuscitationAnalysisGUIUIFigure,'Do you want save the data from the app?', ...
                    'Save Request','Options',["Yes","No"],"DefaultOption",1,"CancelOption",2);
                if strcmpi(saveGUI,'Yes')
                    SaveandExportButtonPushed(app,event)
                    delete(app)
                end
                delete(app)
            end
        end

        % Value changed function: AddBreathButton
        function AddBreathButtonValueChanged(app, event)
            value = app.AddBreathButton.Value;

            if value

                app.manualBreaths(app.anoxia) = app.manualBreaths(app.anoxia)+value;
                
                addInhaleX = round(mean(app.Resp.XLim),3);
                inhaleIndex = find(app.timeSegments(app.anoxia,:)==addInhaleX);
                addInhaleY = app.respSegments(app.anoxia,inhaleIndex);

                addExhaleX = addInhaleX+0.2;
                addExhaleY = app.respSegments(app.anoxia,inhaleIndex+200);

                app.addExhale = drawpoint(app.Resp,'Position',[addExhaleX addExhaleY] ...
                    ,'Tag','addExhale','Color',[0.4940 0.1840 0.5560],'Label','Exhale Trough',...
                'LabelVisible','hover');
                addlistener(app.addExhale,'ROIMoved',@(src,event) pointMoved(app,src,event));

                app.addInhale = drawpoint(app.Resp,'Position',[addInhaleX addInhaleY] ...
                    ,'Tag','addInhale','Label','Inhale Peak','LabelVisible','hover');
                addlistener(app.addInhale,'ROIMoved',@(src,event) pointMoved(app,src,event));

                LINEposition = [addInhaleX 10; addInhaleX -10];
                app.lineECG = drawline(app.ECG,'Position',LINEposition,'InteractionsAllowed',...
                    'none','Color',[0.85 0.85 0.85],'LineWidth',20);
                uistack(app.lineECG,'bottom')

                app.linePiezo = drawline(app.Piezo,'Position',LINEposition,'InteractionsAllowed',...
                    'none','Color',[0.85 0.85 0.85],'LineWidth',20);
                uistack(app.linePiezo,'bottom')

            else

                validI = isvalid(app.addInhale);
                validE = isvalid(app.addExhale);

                if validI && validE

                    delete(app.addInhale), delete(app.addExhale), delete(app.lineECG), delete(app.linePiezo)

                    hold(app.Resp,"on")
                    plot(app.Resp,app.addInhalePosition{app.anoxia}(:,1),...
                        app.addInhalePosition{app.anoxia}(:,2),'Color',[0 0.447 0.741],'LineStyle','none',...
                        'Marker','+','LineWidth',1.5)

                    plot(app.Resp,app.addExhalePosition{app.anoxia}(:,1),...
                        app.addExhalePosition{app.anoxia}(:,2),'Color',[0.49 0.18 0.56],'LineStyle','none',...
                        'Marker','+','LineWidth',1.5)
                    hold(app.Resp,"off")

                    breathAdded = uifigure;
                    uialert(breathAdded,"Breath successfully marked to be added.","Success","Icon","success");
                    uiwait(breathAdded,1.5)
                    delete(breathAdded)
                    
                    app.featureLamp.Color = [1 0 0];

                elseif validI && ~validE

                    app.addExhalePosition{app.anoxia}(app.manualBreaths(app.anoxia),:) = [1 0 1];
                    delete(app.addInhale), delete(app.addExhale), delete(app.lineECG), delete(app.linePiezo)

                    hold(app.Resp,"on")
                    plot(app.Resp,app.addInhalePosition{app.anoxia}(:,1),...
                        app.addInhalePosition{app.anoxia}(:,2),'Color',[0 0.45 0.74],'LineStyle','none',...
                        'Marker','+','LineWidth',1.5)
                    hold(app.Resp,"off")

                    breathAdded = uifigure;
                    uialert(breathAdded,"Breath successfully marked to be added.","Success","Icon","success");
                    uiwait(breathAdded,1.5)
                    delete(breathAdded)

                    app.featureLamp.Color = [1 0 0];

                elseif ~validI && ~validE

                    delete(app.lineECG), delete(app.linePiezo)
                    
                    breathAdded = uifigure;
                    uialert(breathAdded,"No Breath Selected.","Notice","Icon","info");
                    uiwait(breathAdded,1.5)
                    delete(breathAdded) 

                    app.addInhalePosition{app.anoxia}(app.manualBreaths(app.anoxia),:) = [];
                    app.addExhalePosition{app.anoxia}(app.manualBreaths(app.anoxia),:) = [];

                    app.manualBreaths(app.anoxia) = app.manualBreaths(app.anoxia)-1;
                end

            end

            
        end

        % Button pushed function: RecalculateFeaturesButton
        function RecalculateFeaturesButtonPushed(app, event)
            if (app.manualBreaths(app.anoxia) == 0) && (app.breathsRemoved(app.anoxia) == 0) 
                error = msgbox('    You must manually edit breath features to recalculate.', ...
                    'Error','error');
                uiwait(error);
            else
                spliceAndSlice(app)

                functionPipeline(app,3)

                app.featureLamp.Color = [0 1 0];
            end
        end

        % Value changed function: RemoveBreathButton
        function RemoveBreathButtonValueChanged(app, event)
            value = app.RemoveBreathButton.Value;

            if value

                app.breathsRemoved(app.anoxia) = app.breathsRemoved(app.anoxia)+value;

                removeBreathX = round(mean(app.Resp.XLim),3);
                breathIndex = find(app.timeSegments(app.anoxia,:)==removeBreathX);
                removeBreathY = app.respSegments(app.anoxia,breathIndex);

                app.removeBreath = drawpoint(app.Resp,'Position',[removeBreathX removeBreathY] ...
                    ,'Tag','removeBreath','Label','Inhale Onset','LabelVisible','hover',...
                    'Color',[0.6350, 0.0780, 0.1840]);
                addlistener(app.removeBreath,'ROIMoved',@(src,event) pointMoved(app,src,event));

                LINEposition = [removeBreathX 10; removeBreathX -10];
                app.lineECG = drawline(app.ECG,'Position',LINEposition,'InteractionsAllowed',...
                    'none','Color',[0.85 0.85 0.85],'LineWidth',20);
                uistack(app.lineECG,'bottom')

                app.linePiezo = drawline(app.Piezo,'Position',LINEposition,'InteractionsAllowed',...
                    'none','Color',[0.85 0.85 0.85],'LineWidth',20);
                uistack(app.linePiezo,'bottom')

            else

                validR = isvalid(app.removeBreath);


                if validR

                    delete(app.removeBreath)     

                    hold(app.Resp,"on")
                    plot(app.Resp,app.removeBreathPosition{app.anoxia}(:,1),...
                        app.removeBreathPosition{app.anoxia}(:,2),'Color',[0.6350, 0.0780, 0.1840],...
                        'LineStyle','none','LineWidth',1.5,'Marker','x')
                    hold(app.Resp,"off")

                    breathRemoved = uifigure;
                    uialert(breathRemoved,"Breath successfully marked to be removed.","Success","Icon","success");
                    uiwait(breathRemoved,1.5)
                    delete(breathRemoved), delete(app.lineECG), delete(app.linePiezo)

                    app.featureLamp.Color = [1 0 0];

                else

                    breathRemoved = uifigure;
                    uialert(breathRemoved,"No breath selected.",'Notice',"Icon","info");
                    uiwait(breathRemoved,1.5)
                    delete(breathRemoved), delete(app.lineECG), delete(app.linePiezo)

                    app.removeBreathPosition{app.anoxia}(app.breathsRemoved,:) = [];
                    app.breathsRemoved(app.anoxia) = app.breathsRemoved(app.anoxia)-1;

                end
            end

        end

        % Button pushed function: RegularityButton
        function RegularityButtonPushed(app, event)
            RegularityPlotWindow = [app.regularityPosition(app.anoxia,1)-5,...
                app.regularityPosition(app.anoxia,1)+5];
            xlim(app.Resp,RegularityPlotWindow)
        end

        % Button pushed function: TableButton
        function TableButtonPushed(app, event)
            if isempty(findobj(app.tableFig))
                app.tableFig = uifigure("Position",[50 250 1200 200],"Name",'Mean Baseline Measurements');
                app.baseTable = uitable(app.tableFig,"Data",app.baselineFeatTable,"Position",[75 25 1000 145]);
            end
        end

        % Button pushed function: EupneicButton
        function EupneicButtonPushed(app, event)
            eupneicPlotWindow = [app.firstEupneicPosition(app.anoxia,1)-5,...
                app.firstEupneicPosition(app.anoxia,1)+5];
            xlim(app.Resp,eupneicPlotWindow)
        end

        % Menu selected function: InhaleVolumesMenu
        function InhaleVolumesMenuSelected(app, event)
            
            if isequal(app.InhaleVolumesMenu.Checked,'on')
                set(app.volText,'Visible','off')
                app.InhaleVolumesMenu.Checked = 'off';

            elseif isequal(app.InhaleVolumesMenu.Checked,'off')
                if isequal(app.InhaleAmplitudesMenu.Checked,'on')
                    set(app.ampText,'Visible','off')
                    app.InhaleAmplitudesMenu.Checked = 'off';
                end
                set(app.volText,'Visible','on')
                app.InhaleVolumesMenu.Checked = 'on';
            end
        end

        % Menu selected function: InhaleAmplitudesMenu
        function InhaleAmplitudesMenuSelected(app, event)

            if isequal(app.InhaleAmplitudesMenu.Checked,'on')
                set(app.ampText,'Visible','off')
                app.InhaleAmplitudesMenu.Checked = 'off';
            elseif isequal(app.InhaleAmplitudesMenu.Checked,'off')
                if isequal(app.InhaleVolumesMenu.Checked,'on')
                    set(app.volText,'Visible','off')
                    app.InhaleVolumesMenu.Checked = 'off';
                end
                set(app.ampText,'Visible','on')
                app.InhaleAmplitudesMenu.Checked = 'on';
            end
        end

        % Menu selected function: PlotMarkersMenu
        function PlotMarkersMenuSelected(app, event)
            if isequal(app.PlotMarkersMenu.Checked,'off')
                app.PlotMarkersMenu.Checked = 'on';
                set(app.abruptPlot,'Visible','on')
                set(app.GAPlot,'Visible','on')
                set(app.bothPlot,'Visible','on')
                set(app.markerLegd,'Visible','on')
            else
                app.PlotMarkersMenu.Checked = 'off';
                set(app.abruptPlot,'Visible','off')
                set(app.GAPlot,'Visible','off')
                set(app.bothPlot,'Visible','off')
                set(app.markerLegd,'Visible','off')
            end

        end

        % Menu selected function: ShiftFirstApneaMenu
        function ShiftFirstApneaMenuSelected(app, event)
          if app.apneaShift == 1
              app.apneaShift = 2;
             % Shifting the First Apnea point
              app.firstApneaPosition(app.anoxia,:) = [app.startOfApneaX(app.apneaShift)...
                  app.startOfApneaY(app.apneaShift)];
              app.firstApnea.Position = app.firstApneaPosition(app.anoxia,:);

             % Calculating the time from gas on to first apnea
              app.timeToApnea(app.anoxia) = app.firstApneaPosition(app.anoxia,1)...
                  -app.onMarkers(app.anoxia,3);

             % Calculating the time from first apena to return to eupnea
              app.timeToRestore63(app.anoxia) = app.restore63Position(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Shifting the First Gasp point
              app.firstGaspPosition(app.anoxia,:) = [app.firstGaspX(app.apneaShift)...
                  app.firstGaspY(app.apneaShift)];
              app.firstGasp.Position = app.firstGaspPosition(app.anoxia,:);

             % Calculating the time from first apnea to the first gasp
              app.gaspLatency(app.anoxia) = app.firstGaspPosition(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Calculating the time from first apena to restored HR
              app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Finding Gasps
              findGasps(app)

             % Shifting plot window
              firstApneaPlotWindow = [app.firstApneaPosition(app.anoxia,1)-5,...
                  app.firstApneaPosition(app.anoxia,1)+5];
              xlim(app.Resp,firstApneaPlotWindow)

          elseif app.apneaShift == 2
              app.apneaShift = 3;

              % Shifting the First Apnea point
              app.firstApneaPosition(app.anoxia,:) = [app.startOfApneaX(app.apneaShift)...
                  app.startOfApneaY(app.apneaShift)];
              app.firstApnea.Position = app.firstApneaPosition(app.anoxia,:);

             % Calculating the time from gas on to first apnea
              app.timeToApnea(app.anoxia) = app.firstApneaPosition(app.anoxia,1)...
                  -app.onMarkers(app.anoxia,3);

             % Calculating the time from first apena to return to eupnea
              app.timeToRestore63(app.anoxia) = app.restore63Position(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Shifting the First Gasp point
              app.firstGaspPosition(app.anoxia,:) = [app.firstGaspX(app.apneaShift)...
                  app.firstGaspY(app.apneaShift)];
              app.firstGasp.Position = app.firstGaspPosition(app.anoxia,:);

             % Calculating the time from first apnea to the first gasp
              app.gaspLatency(app.anoxia) = app.firstGaspPosition(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Calculating the time from first apena to restored HR
              app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Finding Gasps
              findGasps(app)

             % Shifting plot window
              firstApneaPlotWindow = [app.firstApneaPosition(app.anoxia,1)-5,...
                  app.firstApneaPosition(app.anoxia,1)+5];
              xlim(app.Resp,firstApneaPlotWindow)

          elseif app.apneaShift == 3
              app.apneaShift = 1;

              % Shifting the First Apnea point
              app.firstApneaPosition(app.anoxia,:) = [app.startOfApneaX(app.apneaShift)...
                  app.startOfApneaY(app.apneaShift)];
              app.firstApnea.Position = app.firstApneaPosition(app.anoxia,:);

             % Calculating the time from gas on to first apnea
              app.timeToApnea(app.anoxia) = app.firstApneaPosition(app.anoxia,1)...
                  -app.onMarkers(app.anoxia,3);

             % Calculating the time from first apena to return to eupnea
              app.timeToRestore63(app.anoxia) = app.restore63Position(app.anoxia,1)...
                -app.firstApneaPosition(app.anoxia,1);

             % Shifting the First Gasp point
              app.firstGaspPosition(app.anoxia,:) = [app.firstGaspX(app.apneaShift)...
                  app.firstGaspY(app.apneaShift)];
              app.firstGasp.Position = app.firstGaspPosition(app.anoxia,:);

             % Calculating the time from first apnea to the first gasp
              app.gaspLatency(app.anoxia) = app.firstGaspPosition(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);
              
             % Calculating the time from first apena to restored HR
              app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)...
                  -app.firstApneaPosition(app.anoxia,1);

             % Finding Gasps
              findGasps(app)

             % Shifting plot window
              firstApneaPlotWindow = [app.firstApneaPosition(app.anoxia,1)-5,...
                  app.firstApneaPosition(app.anoxia,1)+5];
              xlim(app.Resp,firstApneaPlotWindow)
          end
        end

        % Menu selected function: HRRestorationMenu
        function HRRestorationMenuSelected(app, event)
            validTime = 0;

            while ~validTime
                timeInput = inputdlg("Enter the aproximate time of desired relocation: (seconds)","Manual HR Restoration",[1 60]);
                adjustTime = str2double(cell2mat(timeInput)); 
                if adjustTime >= app.onMarkers(app.anoxia,3) && adjustTime <= app.onMarkers(app.anoxia+1,3)
                    [~,nearestBeat] = min(abs(app.HRTimes{app.anoxia}-adjustTime));

                    app.HRRestored.Position = [app.RTimes{app.anoxia}(nearestBeat) 0];
                    drawnow;
                    % Saving the position of the point
                    app.HRRestoredPosition(app.anoxia,:) = app.HRRestored.Position;

                    % Calculating the time from first apena to restored HR
                    app.timeToHRRestored(app.anoxia) = app.HRRestoredPosition(app.anoxia,1)...
                        -app.firstApneaPosition(app.anoxia,1);
                    
                    validTime = 1;
                elseif isnan(adjustTime)
                    validTime = 1;
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutoresuscitationAnalysisGUIUIFigure = uifigure('Visible', 'off');
            app.AutoresuscitationAnalysisGUIUIFigure.Name = 'Autoresuscitation Analysis GUI';
            app.AutoresuscitationAnalysisGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @AutoresuscitationAnalysisGUIUIFigureCloseRequest, true);
            app.AutoresuscitationAnalysisGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.challengeLabels = uimenu(app.AutoresuscitationAnalysisGUIUIFigure);
            app.challengeLabels.Text = 'Challenge Labels';
            app.InhaleVolumesMenu = uimenu(app.challengeLabels);
            app.InhaleVolumesMenu.MenuSelectedFcn = createCallbackFcn(app, @InhaleVolumesMenuSelected, true);
            app.InhaleVolumesMenu.Checked = 'on';
            app.InhaleVolumesMenu.Text = 'Inhale Volumes';
            app.InhaleAmplitudesMenu = uimenu(app.challengeLabels);
            app.InhaleAmplitudesMenu.MenuSelectedFcn = createCallbackFcn(app, @InhaleAmplitudesMenuSelected, true);
            app.InhaleAmplitudesMenu.Checked = 'on';
            app.InhaleAmplitudesMenu.Text = 'Inhale Amplitudes';
            app.ChallengeMarkersMenu = uimenu(app.AutoresuscitationAnalysisGUIUIFigure);
            app.ChallengeMarkersMenu.Text = 'Challenge Markers';
            app.PlotMarkersMenu = uimenu(app.ChallengeMarkersMenu);
            app.PlotMarkersMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMarkersMenuSelected, true);
            app.PlotMarkersMenu.Checked = 'on';
            app.PlotMarkersMenu.Text = 'Plot Markers';
            app.EditEventMarkersMenu = uimenu(app.AutoresuscitationAnalysisGUIUIFigure);
            app.EditEventMarkersMenu.Text = 'Edit Event Markers';
            app.ShiftFirstApneaMenu = uimenu(app.EditEventMarkersMenu);
            app.ShiftFirstApneaMenu.MenuSelectedFcn = createCallbackFcn(app, @ShiftFirstApneaMenuSelected, true);
            app.ShiftFirstApneaMenu.Text = 'Shift First Apnea';
            app.HRRestorationMenu = uimenu(app.EditEventMarkersMenu);
            app.HRRestorationMenu.MenuSelectedFcn = createCallbackFcn(app, @HRRestorationMenuSelected, true);
            app.HRRestorationMenu.Text = 'HR Restoration';
            app.GridLayout = uigridlayout(app.AutoresuscitationAnalysisGUIUIFigure);
            app.Resp = uiaxes(app.GridLayout);
            title(app.Resp, 'Respiratory Flow')
            xlabel(app.Resp, 'Trial Time (seconds)')
            ylabel(app.Resp, 'Amplitude')
            zlabel(app.Resp, 'Z')
            app.ECG = uiaxes(app.GridLayout);
            title(app.ECG, 'ECG')
            xlabel(app.ECG, 'Trial Time (seconds)')
            ylabel(app.ECG, 'Amplitude')
            zlabel(app.ECG, 'Z')
            app.Piezo = uiaxes(app.GridLayout);
            title(app.Piezo, 'Piezostrain')
            xlabel(app.Piezo, 'Trial Time (seconds)')
            ylabel(app.Piezo, 'Amplitude')
            zlabel(app.Piezo, 'Z')
            app.NextAnoxiaButton = uibutton(app.GridLayout, 'push');
            app.NextAnoxiaButton.ButtonPushedFcn = createCallbackFcn(app, @NextAnoxiaButtonPushed, true);
            app.NextAnoxiaButton.Text = 'Next Anoxia';
            app.UploadtxtFileButton = uibutton(app.GridLayout, 'push');
            app.UploadtxtFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtxtFileButtonPushed, true);
            app.UploadtxtFileButton.Text = 'Upload .txt File';
            app.PreviousAnoxiaButton = uibutton(app.GridLayout, 'push');
            app.PreviousAnoxiaButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousAnoxiaButtonPushed, true);
            app.PreviousAnoxiaButton.Text = 'Previous Anoxia';
            app.GasOnButton = uibutton(app.GridLayout, 'push');
            app.GasOnButton.ButtonPushedFcn = createCallbackFcn(app, @GasOnButtonPushed, true);
            app.GasOnButton.Text = 'Gas On';
            app.GasOffButton = uibutton(app.GridLayout, 'push');
            app.GasOffButton.ButtonPushedFcn = createCallbackFcn(app, @GasOffButtonPushed, true);
            app.GasOffButton.Text = 'Gas Off';
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '63%';
            app.ApneaButton = uibutton(app.GridLayout, 'push');
            app.ApneaButton.ButtonPushedFcn = createCallbackFcn(app, @ApneaButtonPushed, true);
            app.ApneaButton.Text = 'Apnea';
            app.SaveandExportButton = uibutton(app.GridLayout, 'push');
            app.SaveandExportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandExportButtonPushed, true);
            app.SaveandExportButton.Text = 'Save and Export';
            app.SecondaryPlotsButton = uibutton(app.GridLayout, 'push');
            app.SecondaryPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SecondaryPlotsButtonPushed, true);
            app.SecondaryPlotsButton.Text = 'Secondary Plots';
            app.HRRestoredButton = uibutton(app.GridLayout, 'push');
            app.HRRestoredButton.ButtonPushedFcn = createCallbackFcn(app, @HRRestoredButtonPushed, true);
            app.HRRestoredButton.Text = 'HR Restored';
            app.AddBreathButton = uibutton(app.GridLayout, 'state');
            app.AddBreathButton.ValueChangedFcn = createCallbackFcn(app, @AddBreathButtonValueChanged, true);
            app.AddBreathButton.Text = 'Add Breath';
            app.RecalculateFeaturesButton = uibutton(app.GridLayout, 'push');
            app.RecalculateFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateFeaturesButtonPushed, true);
            app.RecalculateFeaturesButton.Text = 'Recalculate Features';
            app.RemoveBreathButton = uibutton(app.GridLayout, 'state');
            app.RemoveBreathButton.ValueChangedFcn = createCallbackFcn(app, @RemoveBreathButtonValueChanged, true);
            app.RemoveBreathButton.Text = 'Remove Breath';
            app.featureLamp = uilamp(app.GridLayout);
            app.featureLamp.Enable = 'off';
            app.RegularityButton = uibutton(app.GridLayout, 'push');
            app.RegularityButton.ButtonPushedFcn = createCallbackFcn(app, @RegularityButtonPushed, true);
            app.RegularityButton.Text = 'Regularity';
            app.TableButton = uibutton(app.GridLayout, 'push');
            app.TableButton.ButtonPushedFcn = createCallbackFcn(app, @TableButtonPushed, true);
            app.TableButton.Text = 'Table';
            app.EupneicButton = uibutton(app.GridLayout, 'push');
            app.EupneicButton.ButtonPushedFcn = createCallbackFcn(app, @EupneicButtonPushed, true);
            app.EupneicButton.Text = 'Eupneic';
            app.JumptoAnoxiaEditFieldLabel = uilabel(app.GridLayout);
            app.JumptoAnoxiaEditFieldLabel.Text = 'Jump to Anoxia';
            app.JumptoAnoxiaEditField = uieditfield(app.GridLayout, 'numeric');
            app.JumptoAnoxiaEditField.ValueChangedFcn = createCallbackFcn(app, @JumptoAnoxiaEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '/';
            app.NumOfAnoxias = uieditfield(app.GridLayout, 'numeric');
            app.NumOfAnoxias.Enable = 'off';
            app.CurrentFileEditFieldLabel = uilabel(app.GridLayout);
            app.CurrentFileEditFieldLabel.Enable = 'off';
            app.CurrentFileEditFieldLabel.Text = 'Current File';
            app.CurrentFile = uieditfield(app.GridLayout, 'text');
            app.CurrentFile.Enable = 'off';
            % Show the figure after all components are created
            app.AutoresuscitationAnalysisGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARSGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutoresuscitationAnalysisGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutoresuscitationAnalysisGUIUIFigure)
        end
    end
end